classdef APTProject_App < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1          matlab.ui.Figure
    SelPtsEdit       matlab.ui.control.EditField
    SocialCheckBox   matlab.ui.control.CheckBox
    LandmarkstouseforGlobalandBodyfeaturesLeaveemptytousealllandmarksLabel  matlab.ui.control.Label
    BodyCheckBox     matlab.ui.control.CheckBox
    GlobalCheckBox   matlab.ui.control.CheckBox
    edit_social      matlab.ui.control.TextArea
    text4            matlab.ui.control.Label
    pushbutton_help  matlab.ui.control.Button
    text2            matlab.ui.control.Label
    edit_trk         matlab.ui.control.EditField
    cancel_pb        matlab.ui.control.Button
    done_pb          matlab.ui.control.Button
    trx_pop          matlab.ui.control.DropDown
    edit_custom      matlab.ui.control.TextArea
    edit_triad       matlab.ui.control.TextArea
    edit_pair        matlab.ui.control.TextArea
    checkbox5        matlab.ui.control.Label
    checkbox4        matlab.ui.control.Label
    checkbox3        matlab.ui.control.Label
    trx_checkbox     matlab.ui.control.CheckBox
    group_notrx      matlab.ui.container.ButtonGroup
    rb_custom        matlab.ui.control.RadioButton
    text_centroid    matlab.ui.control.Label
    rb_centroid      matlab.ui.control.RadioButton
    rb_crop          matlab.ui.control.RadioButton
    rb_frame         matlab.ui.control.RadioButton
    axes1            matlab.ui.control.UIAxes
  end


  methods (Access = private)
    function handles = UpdateHandles(app, handles)
      handles.use_trx = get(handles.trx_checkbox,'Value');
      if handles.has_trx || handles.is_ma
        trx_type_ndx = get(handles.trx_pop,'Value');
        featureLexiconName = handles.trxList{1}{trx_type_ndx};
        [featureLexicon,animalType]=featureLexiconFromFeatureLexiconName(featureLexiconName);
      else
        featureLexiconName = 'apt';
        animalType = 'apt';
      end
      handles.aptStruct.origFeatureLexiconName = featureLexiconName;
      if handles.has_trx || handles.is_ma
        handles.aptStruct.featureLexiconName = [featureLexiconName '_' handles.aptStruct.projname];
      else
        handles.aptStruct.featureLexiconName = featureLexiconName;
      end

      handles.aptStruct.animalType = animalType;
      
      str = app.SelPtsEdit.Value;
      if isempty(strtrim(str))
        handles.sel_pts = [];
      else
        cur_p = str2double(strsplit(str));
        n_pts = handles.aptStruct.n_pts;
        if any(round(cur_p) ~= cur_p) || any(isnan(cur_p)) || any(cur_p>n_pts) || any(cur_p<1)
          uiwait(errordlg(sprintf('Please specify points as integer and between 1 and %d ',n_pts)));
          app.SelPtsEdit.Value = '';
          handles.sel_pts = [];
        else
          handles.sel_pts = cur_p;
        end
      end
      
      % pairs
      str = get(handles.edit_pair,'String');
      pairs = parse_pairs(app, str{1},handles,true);
      handles.pairs = pairs;

      % triads
      str = get(handles.edit_triad,'String');
      pair_str = strsplit(str{1},',');
      triads = {};
      if ~isempty(pair_str{1}),
        for ndx = 1:numel(pair_str)
          cur_str = strtrim(pair_str{ndx});
          cur_pair = strsplit(cur_str);
          if numel(cur_pair)~=3
            uiwait(errordlg('Incorrect format for specifying triads.'));
            triads = {}; break;
            return;
          end
          pts = [];
          is_error = false;
          for pndx = 1:numel(cur_pair)
            cur_p = str2double(cur_pair{pndx});
            if round(cur_p) ~= cur_p || isnan(cur_p)
              uiwait(errordlg('Please specify points as integer'));
              triads = {}; is_error = true; break;
            end
            if cur_p > handles.aptStruct.n_pts
              uiwait(errordlg(sprintf('Points should be between 1 and %d',handles.aptStruct.n_pts)));
              triads = {};  is_error = true;break;
            end
            pts(end+1) = cur_p;
          end
          if is_error, triads = {}; break; end
          if numel(unique(pts))~=3
            uiwait(errordlg('Cannot use same points twice in the triads'));
            triads = {}; break;
          end
          triads{end+1} = pts;
        end
      end
      handles.triads = triads;

      if handles.is_multi
        str = get(handles.edit_social,'String');
        social_pairs = parse_pairs(app, str{1},handles,false);
        handles.social_pairs = social_pairs;

      end

      % custom
      str = get(handles.edit_custom,'String');
      pair_str = strsplit(str{1},',');
      custom_list = {};
      if ~isempty(pair_str{1}),
        for ndx = 1:numel(pair_str)
          custom_list{ndx} = strtrim(pair_str{ndx});
        end
      end
      handles.custom_list = custom_list;
      trk_file_str = strtrim(get(handles.edit_trk,'String'));
      trkfiles = strsplit(trk_file_str,',');
      handles.aptStruct.trkfilename = trkfiles;
      UpdatePlots(app, handles);
      handles.appDataOut.aptStruct = handles.aptStruct;

    end

    function UpdatePlots(app, handles)
      if isempty(handles.im)
        axis(handles.axes1,'off');
        return
      end
      is_multi = handles.has_trx|handles.is_ma;

      axes(handles.axes1);
      img = handles.im;
      ll = double(handles.locs);
      hsz = size(img,1)/2;

      imh = imshow(img);
      if is_multi
        if ndims(img)>2
          alpha = img(:,:,1);
        else
          alpha = img;
        end
        alpha = double(alpha);
        alpha(hsz+1:end,:) = 0.2;
        alpha(1:hsz,:) = 1.;
        imh.AlphaData = alpha;
        text(12,12,'Current Animal');
        text(12,hsz+12,'Other Animal');
      end
      hold on;
      scatter(ll(:,1),ll(:,2),'.');
      for ndx = 1:size(ll,1)
        cur_n = modrange(ndx,1,handles.aptStruct.n_pts+1);
        text(ll(ndx,1)+1,ll(ndx,2)+1,num2str(cur_n),'Color',[1 0 0]);
      end
      if is_multi
        scatter(ll(:,1),ll(:,2)+hsz,'.')
        for ndx = 1:size(ll,1)
          cur_n = modrange(ndx,1,handles.aptStruct.n_pts+1);
          text(ll(ndx,1)+1,ll(ndx,2)+1+hsz,num2str(cur_n),'Color',[0 1 0]);
        end
      end
      for pndx = 1:numel(handles.pairs)
        for view = 1:handles.aptStruct.n_view
          n_pts = handles.aptStruct.n_pts;
          p = ll(handles.pairs{pndx}+(view-1)*n_pts,:);
          plot(p(:,1),p(:,2));
        end
      end
      if handles.is_multi
        spair = handles.social_pairs;
        for pndx = 1:numel(spair)
          for view = 1:handles.aptStruct.n_view
            p1 = ll(spair{pndx}(1),:);
            p2 = ll(spair{pndx}(2),:);
            p2(2) = p2(2) + hsz;
            plot([p1(1) p2(1)],[p1(2) p2(2)],'Color',[0 0 1],'LineStyle',':');
          end
        end

      end
      hold off;
      handles.im = img;
      handles.locs = ll;
      axis(handles.axes1,'off');
    end

    function trxfile = getTrxFile(app, trxfile, movfile)
      movdir = fileparts(movfile);
      trxfile = strrep(trxfile,'$movdir',movdir);
    end

    function global_checkbox_Callback(app, hObject, eventdata, handles)
      % --- Executes on button press in global_checkbox.

      % hObject    handle to global_checkbox (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hint: get(hObject,'Value') returns toggle state of global_checkbox
      handles = UpdateHandles(app, handles);
      guidata(hObject,handles)
    end

    function handles = initialize(app, handles)
      is_multi = handles.has_trx|handles.is_ma;
      handles.is_multi = is_multi;
      set(handles.trx_pop,'Enable',onoffif(app, is_multi));
      set(handles.trx_pop,'Visible',onoffif(app, is_multi));
      set(handles.trx_checkbox,'Visible',onoffif(app, is_multi));
      set(handles.trx_checkbox,'Value',is_multi);
      set(handles.group_notrx,'Visible',onoffif(app, ~is_multi));
      set(handles.rb_centroid,'Visible',onoffif(app, ~is_multi));
      set(handles.rb_frame,'Visible',onoffif(app, ~is_multi));
      set(handles.rb_crop,'Visible',onoffif(app, ~is_multi));
      set(handles.rb_custom,'Visible',onoffif(app, ~is_multi));
      set(handles.text_centroid,'Visible',onoffif(app, ~is_multi));

      if is_multi
        set(handles.trx_pop,'String',handles.trxList{1})
        trx_type_ndx = find(strcmp(handles.aptStruct.origFeatureLexiconName,handles.trxList{1}),1);
        if ~isempty(trx_type_ndx),
          set(handles.trx_pop,'Value',trx_type_ndx);
        else
          set(handles.trx_pop,'Value',1);
        end
        set(handles.trx_checkbox,'Value',true);
        set(handles.edit_social,'Enable','on')
      else
        set(handles.trx_pop,'String',{''})
        set(handles.trx_pop,'Value',1)
        set(handles.trx_checkbox,'Value',true);
        if handles.has_crops
          set(handles.rb_crop,'Value',true);
          set(handles.rb_frame,'Value',false);
          set(handles.rb_crop,'Enable','on');
        else
          set(handles.rb_crop,'Value',false);
          set(handles.rb_crop,'Enable','off');
          set(handles.rb_frame,'Value',true);
        end
        set(handles.rb_centroid,'Value',false);
        set(handles.edit_social,'Enable','off')
      end

      % load a reference image and point locations.
      apt = handles.apt;
      has_ref_img = false;
      has_mov = true;
      for ndx = 1:size(apt.movieFilesAll,1)
        has_label = false;
        tndx_to_use = 1;
        locs = [];
        if isfield(apt,'labeledpos')
          s = apt.labeledpos{ndx};
          cur_pts = nan(s.size);
          cur_pts(s.idx) = s.val; % scalar expansion for 'log'
          for tndx = 1:size(cur_pts,4)
            frm = find(~isnan(cur_pts(1,1,:,tndx)),1);
            if isempty(frm)
              continue;
            else
              has_label = true;
              tndx_to_use = tndx;
              locs = cur_pts(:,:,frm,tndx);
              break;
            end
          end
        else
          s = apt.labels{ndx};
          assert(isstruct(s));
          cur_pts = s.p;
          if numel(s.frm)>0
            has_label = true;
            frm = s.frm(1);
            tndx_to_use = s.tgt(1);
            locs = reshape(s.p(:,1),[s.npts,2]);
          else
            has_label = false;
          end
        end

        if ~has_label
          continue;
        end

        mov_files = {};
        trx_files = {};
        for m_ndx = 1:size(apt.movieFilesAll,2)
          mov_file = unMacroise(app, apt.movieFilesAll{ndx,m_ndx},apt);
          if ~exist(mov_file,'file')
            [bdir,mname,mext] =fileparts(mov_file);
            qstr = sprintf('JAABA needs movie file %s%s to show a reference labeled example, but it does not exist at location %s. Browse to movie? If you select no, no reference image will be shown.',mname,mext,bdir);
            res = questdlg(qstr,'Missing movie file...','Yes','No','Cancel','Yes');
            if strcmp(res,'Yes')
              [fname,fpath] = uigetfile('',['Missing ' mov_file]);
              if ~isequal(fname,0)
                mov_files{end+1} = fullfile(fpath,fname);
                if handles.has_trx
                  trx_file = getTrxFile(app, apt.trxFilesAll{ndx},mov_file);
                  [fname,fpath] = uigetfile('',['Missing ' trx_file]);
                  trx_files{end+1} = fullfile(fpath,fname);
                end
              else
                has_mov = false;
                break;
              end
            else
              has_mov = false;
              break;
            end
          else
            mov_files{end+1} = mov_file;
            tt = unMacroise(app, apt.trxFilesAll{ndx,m_ndx},apt);
            aa = fileparts(mov_files{end});
            tt = strrep(tt,'$movdir',aa);
            trx_files{end+1} = tt;
          end
        end

        if ~has_mov
          break;
        end

        has_ref_img = true;
        img = []; all_locs = []; prev_width = 0;
        for m_ndx = 1:size(apt.movieFilesAll,2)
          mov_file = mov_files{m_ndx};
          if has_mov
            rfn = get_readframe_fcn(mov_file);
            cur_img = rfn(frm);
          else
            nr = apt.movieInfoAll{ndx,m_ndx}.info.nr;
            nc = apt.movieInfoAll{ndx,m_ndx}.info.nc;
            cur_img = zeros(nr,nc);
          end
          if handles.has_trx
            trx = load(trx_files{m_ndx},'-mat');
            trx = trx.trx(tndx_to_use);
            off = trx.off;
            x = trx.x(frm+off);
            y = trx.y(frm+off);
            theta = trx(tndx_to_use).theta(frm+off);
            [cur_img, aff_mat] = CropImAroundTrx(cur_img,x,y,theta,handles.sz(2),handles.sz(1));
            ll = locs*aff_mat(1:2,1:2);
            ll(:,2) = ll(:,2)+aff_mat(3,2) + handles.sz(1) + 0.5;
            ll(:,1) = ll(:,1)+aff_mat(3,1) + handles.sz(2) + 0.5;
            ll(:,1) = ll(:,1) + prev_width;
          elseif handles.is_ma
            if ~isempty(handles.head_tail)
              h_pt = locs(handles.head_tail(1),:);
              t_pt = locs(handles.head_tail(2),:);
              x = (h_pt(1)+t_pt(1))/2;
              y = (h_pt(2)+t_pt(2))/2;
              dy = (h_pt(2)-t_pt(2));
              dx = (h_pt(1)-t_pt(1));
              theta = atan2( dy,dx );
            else
              x = mean(locs(:,1));
              y = mean(locs(:,2));
              theta = 0;
            end
            [cur_img, aff_mat] = CropImAroundTrx(cur_img,x,y,theta,handles.sz(2),handles.sz(1));
            ll = locs*aff_mat(1:2,1:2);
            ll(:,2) = ll(:,2)+aff_mat(3,2) + handles.sz(1) + 0.5;
            ll(:,1) = ll(:,1)+aff_mat(3,1) + handles.sz(2) + 0.5;
            ll(:,1) = ll(:,1) + prev_width;
          else
            n_pts = handles.aptStruct.n_pts;
            st = (m_ndx-1)*n_pts + 1; en = m_ndx*n_pts;
            ll = locs(st:en,:);
            ll(:,1) = ll(:,1) + prev_width;
          end
          img = cat(2,img,cur_img);
          prev_width = size(img,2);
          all_locs = cat(1,all_locs,ll);
        end
        if is_multi
          img = cat(1,img,img);
        end

        break
      end

      if has_ref_img
        handles.im = img;
        handles.locs = all_locs;
      else
        handles.im = [];
        handles.locs = [];
      end

      set(handles.text_centroid,'String','');

      npts = apt.cfg.NumLabelPoints;
      if isfield(handles.aptStruct,'pairs') && ~isempty(handles.aptStruct.pairs),
        isvalid = all(cellfun(@(x) all(x==round(x)) && numel(x) == 2 && x(1) ~= x(2) && ...
          all(x>=1) && all(x<=npts),handles.aptStruct.pairs));
        if isvalid,
          pairstr = sprintf('%d %d, ',handles.aptStruct.pairs{:});
          pairstr = pairstr(1:end-2);
          set(handles.edit_pair,'String',pairstr);
        end
      end

      if isfield(handles.aptStruct,'triads') && ~isempty(handles.aptStruct.triads),
        isvalid = all(cellfun(@(x) all(x==round(x)) && numel(x) == 3 && numel(unique(x))==3 && ...
          all(x>=1) && all(x<=npts),handles.aptStruct.triads));
        if isvalid,
          str = sprintf('%d %d %d, ',handles.aptStruct.triads{:});
          str = str(1:end-2);
          set(handles.edit_triad,'String',str);
        end
      end

      if isfield(handles.aptStruct,'sel_pts') && ~isempty(handles.aptStruct.sel_pts)
        app.SelPtsEdit.Value = string(handles.aptStruct.sel_pts);
      end

      if isfield(handles.aptStruct,'global_features')
        app.GlobalCheckBox.Value = handles.aptStruct.global_features;
      end
      if isfield(handles.aptStruct,'body_features')
        app.BodyCheckBox.Value = handles.aptStruct.body_features;
      end
      if isfield(handles.aptStruct,'social_features')
        app.SocialCheckBox.Value = handles.aptStruct.social_features;
      end


      if isfield(handles.aptStruct,'custom_list') && ~isempty(handles.aptStruct.custom_list),
        str = sprintf('%s, ',handles.aptStruct.custom_list{:});
        str = str(1:end-2);
        set(handles.edit_custom,'String',str);
      end

      isvalid = ~isempty(handles.aptStruct.trkfilename) && ...
        isvalid_default_trkfilename(app, handles,handles.aptStruct.trkfilename);
      if isvalid,
        str = sprintf('%s, ',handles.aptStruct.trkfilename{:});
        str = str(1:end-2);
        set(handles.edit_trk,'String',str);
      else
        trkfilename = reset_trk_edit(app, handles);
        handles.aptStruct.trkfilename = trkfilename;
      end

      handles.aptStruct.n_pts = apt.cfg.NumLabelPoints;
      handles.aptStruct.n_view = apt.cfg.NumViews;
      handles.aptStruct.projname = apt.projname;
      handles.aptStruct.head_tail = handles.head_tail;
      % handles.aptStruct.featureLexicon = [];
      % handles.aptStruct.featureLexiconName = '';
      % handles.aptStruct.animalType = '';
      handles.aptStruct.view = 1;
      handles.aptStruct.is_ma = handles.is_ma;

      handles = UpdateHandles(app, handles);
    end

    function isvalid = isvalid_default_trkfilename(app, handles, trkfilename)
      apt = handles.apt;
      isvalid = false;
      % check number of views
      if numel(trkfilename) ~= size(apt.movieFilesAll,2),
        return;
      end

      [~,projfile,~] = fileparts(handles.aptStruct.lbl_file);
      for ndx = 1:size(apt.movieFilesAll,2),
        [~,movfilename,~] = fileparts(apt.movieFilesAll{1,ndx});
        basename = [movfilename '_' projfile];
        if ~startsWith(trkfilename{ndx},basename) || ~endsWith(trkfilename{ndx},'.trk'),
          return;
        end

      end
      isvalid = true;
    end

    function onoff = onoffif(app, in)
      if in
        onoff = 'on';
      else
        onoff = 'off';
      end
    end

    function pairs = parse_pairs(app, str, handles, check_match)
      pair_str = strsplit(str,',');
      pairs = {};
      if ~isempty(pair_str{1}),
        for ndx = 1:numel(pair_str)
          cur_str = strtrim(pair_str{ndx});
          cur_pair = strsplit(cur_str);
          if numel(cur_pair)~=2
            uiwait(errordlg('Incorrect format for specifying pairs.'));
            pairs = {}; break;
          end
          pts = [];
          is_error = false;
          for pndx = 1:numel(cur_pair)
            cur_p = str2double(cur_pair{pndx});
            if round(cur_p) ~= cur_p || isnan(cur_p)
              uiwait(errordlg('Please specify points as integer'));
              pairs = {}; is_error = true; break;
            end
            if cur_p > handles.aptStruct.n_pts
              uiwait(errordlg(sprintf('Points should be between 1 and %d',handles.n_pts)));
              pairs = {};  is_error = true;break;
            end
            pts(end+1) = cur_p;
          end
          if is_error
            pairs = {}; break;
          end
          if (pts(1) == pts(2))&&check_match
            uiwait(errordlg('Cannot use same point in pairs'));
            pairs = {}; break;
          end
          pairs{end+1} = pts;
        end
      end
    end

    function reset_rb(app, handles)
      if handles.has_crops
        set(handles.rb_crop,'Value',true);
        set(handles.rb_frame,'Value',false);
      else
        set(handles.rb_crop,'Value',false);
        set(handles.rb_frame,'Value',true);
      end
      set(handles.rb_custom,'Value',false);
      set(handles.rb_centroid,'Value',false);
      set(handles.text_centroid,'String','');
    end

    function all_trkfilenames = reset_trk_edit(app, handles)
      apt = handles.apt;
      trk_file_str = '';
      all_trkfilenames = cell(1,size(apt.movieFilesAll,2));
      for ndx = 1:size(apt.movieFilesAll,2)
        %mov_file = apt.movieFilesAll{1,ndx};
        [~,movfilename,~] = fileparts(apt.movieFilesAll{1,ndx});
        [~,projfile,~] = fileparts(handles.aptStruct.lbl_file);
        trkfilename = [movfilename '_' projfile '.trk'];
        if isempty(trk_file_str)
          trk_file_str = trkfilename;
        else
          trk_file_str = sprintf('%s,%s',trk_file_str,trkfilename);
        end
        all_trkfilenames{ndx} = trkfilename;
      end
      set(handles.edit_trk,'String',trk_file_str)
    end

    function theta_checkbox_Callback(app, hObject, eventdata, handles)
      % --- Executes on button press in theta_checkbox.

      % hObject    handle to theta_checkbox (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hint: get(hObject,'Value') returns toggle state of theta_checkbox
      handles = UpdateHandles(app, handles);
      guidata(hObject,handles)
    end

    function filename = unMacroise(app, filename, apt)
      macros = fieldnames(apt.projMacros);
      for fndx = 1:numel(macros)
        f = macros{fndx};
        filename = strrep(filename,sprintf('$%s',f),apt.projMacros.(f));
      end
    end

  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function APTProject_OpeningFcn(app, varargin)
      % --- Executes just before APTProject is made visible.

      % Ensure that the app appears on screen when run
      movegui(app.figure1, 'onscreen');

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

      % This function has no output args, see OutputFcn.
      % hObject    handle to figure
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      % varargin   command line arguments to APTProject (see VARARGIN)

      % Choose default command line output for APTProject
      handles.output = hObject;
      handles.appDataOut = varargin{1}; % required so that we can pass the back.

      [lbl_file,aptStruct] = myparse(varargin(2:end),'lbl_file','','aptStruct',struct());
      aptStruct.lbl_file = lbl_file;
      if ~isfield(aptStruct,'featureLexicon')
        aptStruct.featureLexicon = [];
        aptStruct.featureLexiconName = '';
      end

      if ~isfield(aptStruct,'origFeatureLexiconName')
        aptStruct.origFeatureLexiconName = '';
      end
      if isfield(aptStruct,'animalType'),
      else
        aptStruct.animalType = '';
      end
      if isfield(aptStruct,'n_pts'),
      else
        aptStruct.n_pts = 0;
      end
      if isfield(aptStruct,'n_view'),
      else
        aptStruct.n_view = 0;
      end
      if isfield(aptStruct,'projname'),
      else
        aptStruct.projname = '';
      end
      if isfield(aptStruct,'trkfilename'),
      else
        aptStruct.trkfilename = '';
      end

      handles.aptStruct = aptStruct;
      handles.appDataOut.aptStruct = aptStruct;

      % Update handles structure
      guidata(hObject, handles);

      % Checks
      if isempty(lbl_file)
        % close the gui if there is no label file.
        % the gui is closed in outputfcn.
        return
      end

      h = waitbar(0,'Loading label file');
      handles.waitbar = h;
      guidata(hObject,handles);
      try
        apt = loadLbl(lbl_file);
      catch ME
        errmsg = sprintf('Could not load label file %s',ME.message);
        uiwait(errordlg(errmsg, 'Unable to load label file'));
        handles.aptStruct.lbl_file = '';
        handles.appDataOut.aptStruct = aptStruct;

        guidata(hObject,handles);
        return;
      end

      if isempty(apt.movieFilesAll)
        uiwait(errordlg('APT Label file should have at least one movie','Empty APT project'));
        return;
      end

      %
      handles.apt = apt;
      handles.has_trx = apt.projectHasTrx;
      if isfield(apt,'maIsMA')
        handles.is_ma = apt.maIsMA;
      else
        handles.is_ma = false;
      end
      handles.has_crops = ~isempty(apt.movieFilesAllCropInfo{1});
      aptStruct.n_pts = apt.cfg.NumLabelPoints;
      aptStruct.skeletonEdges = apt.skeletonEdges;
      if handles.is_ma
        if ~isempty(apt.skelHead)
          handles.head_tail = [apt.skelHead apt.skelTail];
          handles.use_theta = true;
        else
          handles.head_tail = [];
          handles.use_theta = false;
        end
        sz = apt.trackParams.ROOT.MultiAnimal.TargetCrop.ManualRadius;
        handles.sz = [sz,sz];
        %  assert 'APT multi-animal projects not yet implemented';
      elseif handles.has_trx
        if isfield(apt.trackParams.ROOT.ImageProcessing.MultiTarget,'TargetCrop')
          handles.use_theta = apt.trackParams.ROOT.ImageProcessing.MultiTarget.TargetCrop.AlignUsingTrxTheta;
          sz = apt.trackParams.ROOT.ImageProcessing.MultiTarget.TargetCrop.Radius;
        else
          handles.use_theta = apt.trackParams.ROOT.MultiAnimal.TargetCrop.AlignUsingTrxTheta;
          sz = apt.trackParams.ROOT.MultiAnimal.TargetCrop.ManualRadius;
        end
        handles.sz = [sz,sz];
        handles.head_tail = [apt.skelHead apt.skelTail];
      elseif handles.has_crops
        % dont use theta if there is no trx file.
        % might need updating later.
        handles.use_theta = true;
        ff = apt.movieFilesAllCropInfo{1}(1).roi;
        ht = ff(4)-ff(3)+1; wd = ff(2)-ff(1)+1;
        handles.sz = [ht,wd];

        if isfield(apt,'skelHead') && ~isempty(apt.skelHead)
          handles.head_tail = [apt.skelHead,apt.skelTail];
        else
          handles.head_tail = [];
        end

      else
        handles.use_theta = true;
        ht = apt.movieInfoAll{1}.info.nr;
        wd = apt.movieInfoAll{1}.info.nc;
        handles.sz = [ht,wd];

        if isfield(apt,'skelHead') && ~isempty(apt.skelHead)
          handles.head_tail = [apt.skelHead,apt.skelTail];
        else
          handles.head_tail = [];
        end

      end

      [featureList,xmlList] = getFeatureLexiconListsFromXML();
      stFeatures = cellfun(@xmlParamsIsST,xmlList);
      featureList = featureList(~stFeatures);
      xmlList = xmlList(~stFeatures);
      handles.trxList = {featureList, xmlList};

      % handles.global_ftrs = {'velmag'};
      % handles.body_ftrs = {'velmag',...
      %           'distcenter', 'ddistcenter'};
      % handles.theta_ftrs = {'x','y','dx','dy',...
      %           'dtheta', 'sin', 'cos'};
      handles.global_ftrs = {'global_velmag'};
      handles.body_ftrs = {'body_velmag',...
        'body_distcenter', 'body_ddistcenter',...
        'body_x','body_y','body_dx','body_dy',...
        'body_dtheta', 'body_sin', 'body_cos'};
      handles.common_ftrs = [handles.global_ftrs, handles.body_ftrs];
      handles.pair_ftrs = {'velmag',...
        'ddist','dist', ... % These are same body_ftrs
        'x','y','dx','dy',...
        'dtheta','cos','sin',... % these are same theta_ftrs
        'areaswept'};
      handles.triad_ftrs = {'cos','sin','dangle',...
        'area','darea','dlen'};

      waitbar(.5,handles.waitbar,'Reading example frame from movie file');
      handles.aptStruct = aptStruct;
      handles = initialize(app, handles);
      delete(handles.waitbar);
      handles.appDataOut.aptStruct = aptStruct;
      guidata(hObject,handles);
     uiwait(handles.figure1);
    end

    % Button pushed function: cancel_pb
    function cancel_pb_Callback(app, event)
      % --- Executes on button press in cancel_pb.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to cancel_pb (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      handles.aptStruct.featureLexicon = [];
      handles.aptStruct.featureLexiconName = '';
      handles.aptStruct.animalType = '';
      handles.appDataOut.aptStruct = handles.aptStruct;
      guidata(hObject,handles);
      uiresume(handles.figure1);
      figure1CloseRequest(app, event)
    end

    % Selection changed function: group_notrx
    function group_notrx_SelectionChangedFcn(app, event)
      % Handle radio button selection in the "For JAABA Trx Use" group
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      handles = guidata(hObject);
      
      selectedButton = event.NewValue;
      if selectedButton == app.rb_centroid
        in_str = {'Centroid of landmarks to be used as center for JAABA Trx. Eg ''1 2 3''',...
          'Use direction from centroid to this landmark as orientation. Eg ''3''. For no orientation leave it emtpy'};
        gg = inputdlg(in_str,'Select landmarks for JAABA Trx');
        
        if isempty(gg)
          reset_rb(app, handles);
          guidata(hObject,handles);
          return;
        end
        
        bb = strsplit(strtrim(gg{1}));
        centers = [];
        n_pts = handles.aptStruct.n_pts;
        for ndx = 1:numel(bb)
          curo = str2double(bb{ndx});
          if isnan(curo) || round(curo) - curo ~= 0 || curo < 1 || curo > n_pts
            uiwait(errordlg(sprintf('Please input only integers or integers less than %d',n_pts)));
            reset_rb(app, handles);
            guidata(hObject,handles);
            return;
          end
          centers(end+1) = curo;
        end
        
        if isempty(strtrim(gg{2})),
          orient = 0;
        else
          bb = strsplit(strtrim(gg{2}));
          if numel(bb)>1
            uiwait(errordlg('Please input only 1 integer for orientation'));
            reset_rb(app, handles);
            guidata(hObject,handles);
            return;
          end
          
          curo = str2double(bb{1});
          if isnan(curo) || round(curo) - curo ~= 0 || curo < 1 || curo > handles.aptStruct.n_pts
            uiwait(errordlg(sprintf('Please input only integers for orientation or integers less than %d',n_pts)));
            reset_rb(app, handles);
            guidata(hObject,handles);
            return;
          end
          orient = curo;
        end
        
        handles.apt_trx_centers = centers;
        handles.apt_trx_orient = orient;
        handles.apt_trx_type = 'trk';
        tt = sprintf('%d ', centers);
        cstr = sprintf('Centroid: %s',tt);
        tstr = sprintf('Orientation: %d',orient);
        set(handles.text_centroid,'String',{cstr,tstr});
        guidata(hObject,handles);
      end
    end

    % Button pushed function: done_pb
    function done_pb_Callback(app, event)
      % --- Executes on button press in done_pb.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to done_pb (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      handles = UpdateHandles(app, handles);
      handles.perframe = struct;
      if handles.has_trx || handles.is_ma
        featureLexiconName = handles.aptStruct.origFeatureLexiconName;
        [featureLexicon,animalType]=featureLexiconFromFeatureLexiconName(featureLexiconName);
        if ~handles.use_trx
          f = fieldnames(featureLexicon.perframe);
          for fndx = 1:numel(f)
            featureLexicon.perframe = rmfield(featureLexicon.perframe,f{fndx});
          end
        end
      else
        featureLexiconName = 'apt';
        animalType = 'apt';
        [featureLexicon,~]=featureLexiconFromFeatureLexiconName('flies');
        f = fieldnames(featureLexicon.perframe);
        for fndx = 1:numel(f)
          featureLexicon.perframe = rmfield(featureLexicon.perframe,f{fndx});
        end
      end

      handles.aptStruct.animalType = animalType;
      trans_type = {'none' 'relative'};
      common_struct = struct();
      common_struct.trans_types=trans_type;
      common_struct.type='apt_locomotion';
      dphi_struct = common_struct;
      dphi_struct.trans_types = {'none' 'abs'};
      dtheta_struct = common_struct;
      dtheta_struct.trans_types = {'none'};

      n_pts = handles.aptStruct.n_pts;
      n_view = handles.aptStruct.n_view;
      % cur_set = handles.common_ftrs;
      cur_set = {};
      if app.GlobalCheckBox.Value
        cur_set = [cur_set,handles.global_ftrs];
      end
      if app.BodyCheckBox.Value
        cur_set = [cur_set,handles.body_ftrs];
      end

      pts2use = handles.sel_pts;
      if numel(pts2use) == 0
        pts2use = 1:n_pts;
      end

      for fndx = 1:numel(cur_set)
        for ndx = pts2use
          for view = 1:n_view
            cur_ftr = sprintf('apt_view%d_%s_%d',view,cur_set{fndx},ndx);
            if contains(cur_ftr,'dphi')
              featureLexicon.perframe.(cur_ftr) = dphi_struct;
            elseif contains(cur_ftr,'dtheta')
              featureLexicon.perframe.(cur_ftr) = dtheta_struct;
            else
              featureLexicon.perframe.(cur_ftr) = common_struct;
            end
          end
        end
      end

      % if handles.use_global
      %   if handles.has_trx
      %     cur_set = handles.global_ftrs;
      %   else
      %     cur_set = cat(2,handles.global_ftrs,handles.theta_ftrs);
      %   end
      %   for fndx = 1:numel(cur_set)
      %     for ndx = 1:n_pts
      %       cur_ftr = sprintf('apt_global_%s_%d',cur_set{fndx},ndx);
      %       if contains(cur_ftr,'dphi')
      %         featureLexicon.perframe.(cur_ftr) = dphi_struct;
      %       elseif contains(cur_ftr,'dtheta')
      %         featureLexicon.perframe.(cur_ftr) = dtheta_struct;
      %       else
      %         featureLexicon.perframe.(cur_ftr) = common_struct;
      %       end
      %     end
      %   end
      % end
      % if handles.use_body
      %   cur_set = handles.body_ftrs;
      %   for fndx = 1:numel(cur_set)
      %     for ndx = 1:n_pts
      %       cur_ftr = sprintf('apt_body_%s_%d',cur_set{fndx},ndx);
      %       if contains(cur_ftr,'dphi')
      %         featureLexicon.perframe.(cur_ftr) = dphi_struct;
      %       elseif contains(cur_ftr,'dtheta')
      %         featureLexicon.perframe.(cur_ftr) = dtheta_struct;
      %       else
      %         featureLexicon.perframe.(cur_ftr) = common_struct;
      %       end
      %     end
      %   end
      % end
      % if handles.use_theta
      %   cur_set = handles.theta_ftrs;
      %   for fndx = 1:numel(cur_set)
      %     for ndx = 1:n_pts
      %       cur_ftr = sprintf('apt_body_%s_%d',cur_set{fndx},ndx);
      %       if contains(cur_ftr,'dphi')
      %         featureLexicon.perframe.(cur_ftr) = dphi_struct;
      %       elseif contains(cur_ftr,'dtheta')
      %         featureLexicon.perframe.(cur_ftr) = dtheta_struct;
      %       else
      %         featureLexicon.perframe.(cur_ftr) = common_struct;
      %       end
      %     end
      %   end
      % end

      % pair features
      for pndx = 1:numel(handles.pairs)
        cur_set = handles.pair_ftrs;
        cur_p = handles.pairs{pndx};
        for fndx = 1:numel(cur_set)
          for view = 1:n_view

            cur_ftr = sprintf('apt_view%d_pair_%s_%d_%d',view,cur_set{fndx},cur_p(1),cur_p(2));
            if contains(cur_ftr,'dphi')
              featureLexicon.perframe.(cur_ftr) = dphi_struct;
            elseif contains(cur_ftr,'dtheta')
              featureLexicon.perframe.(cur_ftr) = dtheta_struct;
            else
              featureLexicon.perframe.(cur_ftr) = common_struct;
            end
          end
        end
      end
      % triad features
      for pndx = 1:numel(handles.triads)
        cur_set = handles.triad_ftrs;
        cur_p = handles.triads{pndx};
        for fndx = 1:numel(cur_set)
          for view = 1:n_view
            cur_ftr = sprintf('apt_view%d_triad_%s_%d_%d_%d',view,cur_set{fndx},cur_p(1),cur_p(2),cur_p(3));
            if contains(cur_ftr,'dphi')
              featureLexicon.perframe.(cur_ftr) = dphi_struct;
            elseif contains(cur_ftr,'dtheta')
              featureLexicon.perframe.(cur_ftr) = dtheta_struct;
            else
              featureLexicon.perframe.(cur_ftr) = common_struct;
            end
          end
        end
      end

      if handles.is_multi && app.SocialCheckBox.Value
        social_struct = struct();
        social_struct.trans_types=trans_type;
        social_struct.type='apt_social';

        for ndx = pts2use
          for view = 1:n_view
            cur_ftr = sprintf('apt_view%d_social_dist_%d',view,ndx);
            featureLexicon.perframe.(cur_ftr) = social_struct;
            cur_ftr = sprintf('apt_view%d_social_ddist_%d',view,ndx);
            featureLexicon.perframe.(cur_ftr) = social_struct;
            cur_ftr = sprintf('apt_view%d_social_sin_%d',view,ndx);
            featureLexicon.perframe.(cur_ftr) = social_struct;
            cur_ftr = sprintf('apt_view%d_social_cos_%d',view,ndx);
            featureLexicon.perframe.(cur_ftr) = social_struct;
          end
        end


        % social pair features
        for pndx = 1:numel(handles.social_pairs)
          cur_p = handles.social_pairs{pndx};
          for view = 1:n_view
            cur_ftr = sprintf('apt_view%d_socialpair_dist_%d_%d',view,cur_p(1),cur_p(2));
            featureLexicon.perframe.(cur_ftr) = social_struct;
            cur_ftr = sprintf('apt_view%d_socialpair_ddist_%d_%d',view,cur_p(1),cur_p(2));
            featureLexicon.perframe.(cur_ftr) = social_struct;
            cur_ftr = sprintf('apt_view%d_socialpair_sin_%d_%d',view,cur_p(1),cur_p(2));
            featureLexicon.perframe.(cur_ftr) = social_struct;
            cur_ftr = sprintf('apt_view%d_socialpair_cos_%d_%d',view,cur_p(1),cur_p(2));
            featureLexicon.perframe.(cur_ftr) = social_struct;
          end
        end
      end

      for pndx = 1:numel(handles.custom_list)
        trans_type = {'none' 'relative'};
        cur_ftr = handles.custom_list{pndx};
        featureLexicon.perframe.(cur_ftr) = common_struct;
      end
      handles.aptStruct.featureLexicon = featureLexicon;
      handles.aptStruct.has_trx = handles.has_trx;
      handles.aptStruct.has_crops = handles.has_crops;
      handles.aptStruct.pairs = handles.pairs;
      handles.aptStruct.triads = handles.triads;
      handles.aptStruct.custom_list = handles.custom_list;

      handles.aptStruct.global_features = app.GlobalCheckBox.Value;
      handles.aptStruct.body_features = app.BodyCheckBox.Value;
      handles.aptStruct.social_features = app.SocialCheckBox.Value;

      if handles.has_trx
        handles.aptStruct.apt_trx_type = '';
      elseif handles.is_ma
        handles.aptStruct.apt_trx_type = 'ma';
        handles.aptStruct.ma_head_tail = handles.head_tail;
        handles.aptStruct.ma_use_theta = handles.use_theta;
      else
        if get(handles.rb_frame,'Value')
          handles.aptStruct.apt_trx_type = 'frame';
        elseif  get(handles.rb_crop,'Value')
          handles.aptStruct.apt_trx_type = 'crop';
        elseif  get(handles.rb_custom,'Value')
          handles.aptStruct.apt_trx_type = 'custom';
        else
          handles.aptStruct.apt_trx_type = 'trk';
          handles.aptStruct.apt_trx_centers = handles.apt_trx_centers;
          handles.aptStruct.apt_trx_orient = handles.apt_trx_orient;
        end
      end
      handles.appDataOut.aptStruct = handles.aptStruct;
      guidata(hObject,handles);
      uiresume(handles.figure1);
      figure1CloseRequest(app, event)
    end

    % Value changed function: edit_custom
    function edit_custom_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to edit_custom (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hints: get(hObject,'String') returns contents of edit_custom as text
      %        str2double(get(hObject,'String')) returns contents of edit_custom as a double
      handles = UpdateHandles(app, handles);
      guidata(hObject,handles)
    end

    % Value changed function: edit_pair
    function edit_pair_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to edit_pair (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hints: get(hObject,'String') returns contents of edit_pair as text
      %        str2double(get(hObject,'String')) returns contents of edit_pair as a double
      handles = UpdateHandles(app, handles);
      guidata(hObject,handles)
    end

    % Value changed function: edit_social
    function edit_social_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to edit_social (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hints: get(hObject,'String') returns contents of edit_social as text
      %        str2double(get(hObject,'String')) returns contents of edit_social as a double
      handles = UpdateHandles(app, handles);
      guidata(hObject,handles)
    end

    % Value changed function: edit_triad
    function edit_triad_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to edit_triad (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hints: get(hObject,'String') returns contents of edit_triad as text
      %        str2double(get(hObject,'String')) returns contents of edit_triad as a double
      handles = UpdateHandles(app, handles);
      guidata(hObject,handles)
    end

    % Value changed function: edit_trk
    function edit_trk_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to edit_trk (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hints: get(hObject,'String') returns contents of edit_trk as text
      %        str2double(get(hObject,'String')) returns contents of edit_trk as a double
      trk_file_str = strtrim(get(hObject,'String'));
      trkfiles = strsplit(trk_file_str,',');
      if numel(trkfiles)~=handles.aptStruct.n_view
        uiwait(errormsg('Number of trk files should be same as number of views'));
        handles.aptStruct.trkfilename = reset_trk_edit(app, handles);
        handles.appDataOut.aptStruct = handles.aptStruct;
        guidata(hObject,handels);
        return;
      end
      handles.aptStruct.trkfilename = trkfiles;
      handles.appDataOut.aptStruct = handles.aptStruct;
      guidata(hObject,handles);
    end

    % Button pushed function: pushbutton_help
    function pushbutton_help_Callback(app, event)
      % --- Executes on button press in pushbutton_help.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to pushbutton_help (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      web('https://github.com/kristinbranson/APT/wiki/Using-APT-tracking-data-in-JAABA','-browser');
    end

    % Value changed function: trx_pop
    function trx_pop_Callback(app, event)
      % --- Executes on selection change in trx_pop.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to trx_pop (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hints: contents = cellstr(get(hObject,'String')) returns trx_pop contents as cell array
      %        contents{get(hObject,'Value')} returns selected item from trx_pop
      handles = UpdateHandles(app, handles);
      guidata(hObject,handles)
    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      delete(app)      
    end

    % Value changed function: GlobalCheckBox
    function GlobalCheckBoxValueChanged(app, event)

    end

    % Value changed function: SelPtsEdit
    function SelPtsEditValueChanged(app, event)
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      handles = UpdateHandles(app, handles);
      guidata(hObject,handles)
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'APTProject';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.Tag = 'figure1';
      app.axes1 = uiaxes(app.figure1);
      app.axes1.Tag = 'axes1';
      app.group_notrx = uibuttongroup(app.figure1);
      app.group_notrx.SelectionChangedFcn = createCallbackFcn(app, @group_notrx_SelectionChangedFcn, true);
      app.group_notrx.Title = 'For JAABA Trx Use';
      app.group_notrx.Tag = 'group_notrx';
      app.rb_frame = uiradiobutton(app.group_notrx);
      app.rb_frame.Tag = 'rb_frame';
      app.rb_frame.Text = 'Frame center';
      app.rb_frame.Value = true;
      app.rb_crop = uiradiobutton(app.group_notrx);
      app.rb_crop.Tag = 'rb_crop';
      app.rb_crop.Text = 'Crop center';
      app.rb_centroid = uiradiobutton(app.group_notrx);
      app.rb_centroid.Tag = 'rb_centroid';
      app.rb_centroid.Text = 'APT output ';
      app.text_centroid = uilabel(app.group_notrx);
      app.text_centroid.Tag = 'text_centroid';
      app.text_centroid.Text = {'text'; 'text'};
      app.rb_custom = uiradiobutton(app.group_notrx);
      app.rb_custom.Tag = 'rb_custom';
      app.rb_custom.Text = 'Custom Trx';
      app.trx_checkbox = uicheckbox(app.figure1);
      app.trx_checkbox.Tag = 'trx_checkbox';
      app.trx_checkbox.Text = 'Trx Features';
      app.checkbox3 = uilabel(app.figure1);
      app.checkbox3.Tag = 'checkbox3';
      app.checkbox3.Text = {'Paired Landmark Features'; 'Separate pairs using comma'; '(Eg: ''1 5, 2 3, 7 8'')'};
      app.checkbox4 = uilabel(app.figure1);
      app.checkbox4.Tag = 'checkbox4';
      app.checkbox4.Text = {'Triad Landmark Features'; 'Separate triads using comma'; '(Eg: ''1 5 2, 5 2 1, 1 7 8'')'};
      app.checkbox5 = uilabel(app.figure1);
      app.checkbox5.Tag = 'checkbox5';
      app.checkbox5.Tooltip = 'Use Custom Features to include your own pre-computed per-frame features that are not implemented within JAABA. ';
      app.checkbox5.Text = {'Custom Features'; 'Separate names using comma'; '(Eg: ''myfeature1, myfeature2'')'};
      app.edit_pair = uitextarea(app.figure1);
      app.edit_pair.ValueChangedFcn = createCallbackFcn(app, @edit_pair_Callback, true);
      app.edit_pair.Tag = 'edit_pair';
      app.edit_triad = uitextarea(app.figure1);
      app.edit_triad.ValueChangedFcn = createCallbackFcn(app, @edit_triad_Callback, true);
      app.edit_triad.Tag = 'edit_triad';
      app.edit_custom = uitextarea(app.figure1);
      app.edit_custom.ValueChangedFcn = createCallbackFcn(app, @edit_custom_Callback, true);
      app.edit_custom.Tag = 'edit_custom';
      app.trx_pop = uidropdown(app.figure1);
      app.trx_pop.Items = {'Pop-up Menu'};
      app.trx_pop.ValueChangedFcn = createCallbackFcn(app, @trx_pop_Callback, true);
      app.trx_pop.Tag = 'trx_pop';
      app.trx_pop.Value = 'Pop-up Menu';
      app.done_pb = uibutton(app.figure1, 'push');
      app.done_pb.ButtonPushedFcn = createCallbackFcn(app, @done_pb_Callback, true);
      app.done_pb.Tag = 'done_pb';
      app.done_pb.Text = 'Done';
      app.cancel_pb = uibutton(app.figure1, 'push');
      app.cancel_pb.ButtonPushedFcn = createCallbackFcn(app, @cancel_pb_Callback, true);
      app.cancel_pb.Tag = 'cancel_pb';
      app.cancel_pb.Text = 'Cancel';
      app.edit_trk = uieditfield(app.figure1, 'text');
      app.edit_trk.ValueChangedFcn = createCallbackFcn(app, @edit_trk_Callback, true);
      app.edit_trk.Tag = 'edit_trk';
      app.text2 = uilabel(app.figure1);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Trk File Name';
      app.pushbutton_help = uibutton(app.figure1, 'push');
      app.pushbutton_help.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_help_Callback, true);
      app.pushbutton_help.Tag = 'pushbutton_help';
      app.pushbutton_help.Tooltip = 'Opens the documentation in a browser';
      app.pushbutton_help.Text = 'Help';
      app.text4 = uilabel(app.figure1);
      app.text4.Tag = 'text4';
      app.text4.Tooltip = 'First number in a pair is the landmark on the current animal';
      app.text4.Text = {'Social Landmark Features'; 'Separate pairs using comma'; '(Eg: ''1 1, 2 3, 2 2'')'};
      app.edit_social = uitextarea(app.figure1);
      app.edit_social.ValueChangedFcn = createCallbackFcn(app, @edit_social_Callback, true);
      app.edit_social.Tag = 'edit_social';
      app.GlobalCheckBox = uicheckbox(app.figure1);
      app.GlobalCheckBox.ValueChangedFcn = createCallbackFcn(app, @GlobalCheckBoxValueChanged, true);
      app.GlobalCheckBox.Text = 'Global';
      app.GlobalCheckBox.Value = true;
      app.BodyCheckBox = uicheckbox(app.figure1);
      app.BodyCheckBox.Text = 'Body';
      app.BodyCheckBox.Value = true;
      app.LandmarkstouseforGlobalandBodyfeaturesLeaveemptytousealllandmarksLabel = uilabel(app.figure1);
      app.LandmarkstouseforGlobalandBodyfeaturesLeaveemptytousealllandmarksLabel.Text = 'Landmarks to use for Global and Body features. Leave empty to use all landmarks';
      app.SocialCheckBox = uicheckbox(app.figure1);
      app.SocialCheckBox.Text = 'Social';
      app.SocialCheckBox.Value = true;
      app.SelPtsEdit = uieditfield(app.figure1, 'text');
      app.SelPtsEdit.ValueChangedFcn = createCallbackFcn(app, @SelPtsEditValueChanged, true);
      app.SelPtsEdit.Tag = 'sel_pts';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = APTProject_App(varargin)

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.figure1)

        % Execute the startup function
        runStartupFcn(app, @(app)APTProject_OpeningFcn(app, varargin{:}))
      else

        % Focus the running singleton app
        figure(runningApp.figure1)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end