classdef ProjectSetup_App < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figureProjectSetup         matlab.ui.Figure
    txST                       matlab.ui.control.Label
    pushbutton_removeadvanced  matlab.ui.control.Button
    pushbutton_addadvanced     matlab.ui.control.Button
    pushbutton_perframe        matlab.ui.control.Button
    config_table               matlab.ui.control.Table
    togglebutton_advanced      matlab.ui.control.StateButton
    pushbutton_copy            matlab.ui.control.Button
    pushbutton_done            matlab.ui.control.Button
    pushbutton_cancel          matlab.ui.control.Button
    text10                     matlab.ui.control.Label
    text9                      matlab.ui.control.Label
    edittrxfilename            matlab.ui.control.EditField
    editmoviefilename          matlab.ui.control.EditField
    text6                      matlab.ui.control.Label
    editscorefilename          matlab.ui.control.EditField
    text3                      matlab.ui.control.Label
    featureconfigpopup         matlab.ui.control.DropDown
    editName                   matlab.ui.control.EditField
    uibuttongroup1             matlab.ui.container.ButtonGroup
    pushbutton_edit_APT        matlab.ui.control.Button
    text_st                    matlab.ui.control.Label
    checkbox_st                matlab.ui.control.CheckBox
    text_apt                   matlab.ui.control.Label
    radiobutton_apt            matlab.ui.control.RadioButton
    radiobutton_list           matlab.ui.control.RadioButton
  end


  methods (Access = private)
    function handles = AddConfig(app, handles, name, value)
      % -------------------------------------------------------------------------


      % If value is a string, convert to a double matrix, if that works
      if ischar(value) && ~isempty(str2num(value)),  %#ok<ST2NM>
        value = str2num(value);  %#ok
      end
      evalStr=sprintf('handles.basicParamsStruct.%s = value;',name);
      eval(evalStr);

      return
    end

    function handles = EditConfigName(app, handles, oldName, newName)
      % -------------------------------------------------------------------------

      eval_str = sprintf(...
        'value = handles.basicParamsStruct.%s;',...
        oldName);
      eval(eval_str);
      handles = AddConfig(app, handles,newName,value);
      handles = RemoveConfig(app, handles,oldName);
      return
    end

    function handles = EditConfigValue(app, handles, name, value)
      % -------------------------------------------------------------------------


      switch name
        case 'behaviors.names'
          value = regexp(value,',','split'); %#ok<NASGU>
        otherwise
      end

      % try to convert it to a number if possible
      if ischar(value)
        valuen = regexp(value,',','split');
      else
        valuen = value;
      end
      valuen = str2double(valuen);
      if all(~isnan(valuen)),
        value = valuen; %#ok<NASGU>
      end
      eval_str = sprintf('handles.basicParamsStruct.%s = value;',name);
      eval(eval_str);
      return
    end

    function [featureLexiconPFNames, isSelected, missingPFNames] = GetAllPerframeList(app, featureLexicon, sublexiconPFNames)
      % -------------------------------------------------------------------------

      % Returns the list of all per-frame feature names in the lexicon indicated
      % by featureLexiconName.  Also returns isSelected, a boolean array of the
      % same size as featureLexiconPFNames, which is true iff that PF is in the
      % sublexiconPFNames list.  Finally, missingPFNames is a list of all PF
      % names in sublexiconPFNames that are not present in
      % featureLexiconPFNames.

      %featureLexiconName=handles.basicParamsStruct.featureLexiconName;
      %featureconfigfile = basicParamsStruct.file.featureconfigfile;
      %basicParamsStruct = ReadXMLParams(featureconfigfile);
      %allPfList = fieldnames(basicParamsStruct.perframe);
      featureLexiconPFNames = fieldnames(featureLexicon.perframe);
      nFeatureLexiconPFNames=length(featureLexiconPFNames);
      isSelected = false(nFeatureLexiconPFNames,1);
      missingPFNames = {};
      %toBeCalculatedPFNames = basicParamsStruct.toBeCalculatedPFNames;
      if isempty(sublexiconPFNames),
        isSelected = true(nFeatureLexiconPFNames,1);
        missingPFNames = {};
      else
        for ndx = 1:length(sublexiconPFNames),
          sublexiconPFName=sublexiconPFNames{ndx};
          allndx = find(strcmp(sublexiconPFName,featureLexiconPFNames));
          if isempty(allndx)
            missingPFNames{end+1} = sublexiconPFName;  %#ok
          else
            isSelected(allndx) = true;
          end
        end
      end
      return
    end

    function data = GetParamsAsTable(app, basicParamsStruct)
      % -------------------------------------------------------------------------

      data = addToList(app, basicParamsStruct,{},'');
      idx = cellfun(@iscell,data(:,2));
      if any(idx),
        for i = find(idx(:)'),
          if all(cellfun(@ischar,data{i,2})),
            data{i,2} = sprintf('%s,',data{i,2}{:});
            if numel(data{i,2})>0 && data{i,2}(end) == ',',
              data{i,2} = data{i,2}(1:end-1);
            end
          end
        end
      end

      if any(cellfun(@iscell,data(:,2))),
        data = {};
        return;
      end
    end

    function handles = RemoveConfig(app, handles, name)
      % -------------------------------------------------------------------------


      [fieldNamePath,dotLastField] = splitext(name);
      if isempty(dotLastField)
        handles.basicParamsStruct = rmfield(handles.basicParamsStruct,fieldNamePath);
      else
        lastField=dotLastField(2:end);  %#ok
        evalStr = sprintf(...
          'handles.basicParamsStruct.%s = rmfield(handles.basicParamsStruct.%s,lastField);',...
          fieldNamePath,fieldNamePath);
        eval(evalStr);
      end
    end

    function list = addToList(app, curStruct, list, pathTillNow)
      % -------------------------------------------------------------------------

      if isempty(fieldnames(curStruct)), return; end
      fnames = fieldnames(curStruct);
      for ndx = 1:numel(fnames)
        if isstruct(curStruct.(fnames{ndx})),
          list = addToList(app, curStruct.(fnames{ndx}),list,[pathTillNow fnames{ndx} '.']);
        else
          list{end+1,1} = [pathTillNow fnames{ndx}]; %#ok<AGROW>
          param = curStruct.(fnames{ndx});
          if isnumeric(param)
            if isempty(param)
              q='';
            else
              q = num2str(param(1));
              for jj = 2:numel(param)
                q = [q ',' num2str(param(jj))]; %#ok<AGROW>
              end
            end
            list{end,2} = q;
          else
            list{end,2} = param;
          end
        end
      end
    end

    function behaviorsStruct = enforceBehaviorParamConstraints(app, behaviorsStruct, dowarn)

      if ~exist('dowarn','var')
        dowarn = false;
      end

      % Add no-behavior names
      behaviorsStruct.names = Labels.behnames2labelnames(behaviorsStruct.names);

      nlabels = numel(behaviorsStruct.names);
      if numel(behaviorsStruct.labelcolors)~=3*nlabels
        if dowarn
          warning('ProjectSetup:behaviorParams',...
            'Specified label colors not consistent with number of behaviors. Updating.');
        end
        behaviorsStruct.labelcolors = Labels.cropOrAugmentLabelColors(behaviorsStruct.labelcolors,nlabels,'lines');
      end
    end

    function handles = fileNameEditTwiddled(app, handles, editName)
      % -------------------------------------------------------------------------

      % Deal with one of the file name edits being modified.
      % This is like a controller method.
      varName=editName(5:end);  % trim 'edit' off of start
      newFileName=strtrim(get(handles.(editName),'String'));
      allowEmpty = ismember(varName,{'moviefilename'});
      allowMulti = ismember(varName,{'scorefilename','moviefilename'});
      [handles,whatHappened]=setFileName(app, handles,varName,newFileName,allowEmpty,allowMulti);
      warnIfInvalid(app, varName,whatHappened);
      updateFileNameEdit(app, handles,editName);
      updateConfigTable(app, handles);
      return
    end

    function handles = setBasicAndAdvancedSizesToMatchFigure(app, handles)
      % -------------------------------------------------------------------------

      % Derive the basic and advanced sizes (part of the model) from
      % the current figure dimensions
      curPos = get(handles.figureProjectSetup,'Position');
      tablePos = get(handles.config_table,'Position');
      reducedWidth = tablePos(1)-15;
      reducedHeight = curPos(4);
      handles.advancedSize = curPos(3:4);
      handles.basicSize = [reducedWidth reducedHeight];
      return
    end

    function [handles, whatHappened] = setFileName(app, handles, varName, newFileName, allowEmpty, allowMulti)
      % -------------------------------------------------------------------------

      % Set the given file name in the model, if it's a valid name.  whatHappened
      % is a string that can be 'notChanged', 'emptyEntry','changed', or
      % 'invalidEntry', depending.

      fileName=handles.basicParamsStruct.file.(varName);

      if allowEmpty && isempty(newFileName),
        handles.basicParamsStruct.file.(varName)=newFileName;
        if strcmp(newFileName,fileName),
          whatHappened='notChanged';
        else
          whatHappened='changed';
        end
        return;
      end

      if isequal(newFileName,fileName)
        whatHappened='notChanged';
      elseif isempty(newFileName)
        whatHappened='emptyEntry';
      elseif ~allowMulti && IsNiceFileName(newFileName)
        whatHappened='changed';
        handles.basicParamsStruct.file.(varName)=newFileName;
      elseif allowMulti
        newFileNameCellStr = String.commaSepList2CellStr(newFileName);
        if all(cellfun(@IsNiceFileName,newFileNameCellStr))
          whatHappened = 'changed';
          handles.basicParamsStruct.file.(varName)=newFileNameCellStr;
        else
          whatHappened = 'invalidEntry';
        end
      else
        whatHappened='invalidEntry';
      end

      return
    end

    function updateConfigTable(app, handles)      
      % -------------------------------------------------------------------------

      % Update the config table (a GUI element) to match the current "model"
      % state
      basicParamsStruct = handles.basicParamsStruct;
      fields2remove = {'featureLexicon','windowFeaturesParams','scoreFeatures', ...
        'sublexiconPFNames','labels','gtLabels','expDirNames', ...
        'gtExpDirNames', 'classifierStuff', 'version','classifierfile',...
        'windowFeaturesParams','aptInfo'};
      if ~handles.new
        editModeRemove = {'featureLexiconName' 'behaviors.names' 'behaviors.type' ...
          'behaviors.nbeh' 'file.trxfilename' 'extra.perframe'};
        fields2remove = [fields2remove editModeRemove];
      end
      basicParamsStruct = structrmfield(basicParamsStruct,fields2remove);
      data = GetParamsAsTable(app, basicParamsStruct);
      set(handles.config_table,'Data',data);
      return
    end

    function updateEditsListboxesAndPopupmenus(app, handles)
      % -------------------------------------------------------------------------


      % update the behavior name editbox
      if isfield(handles.basicParamsStruct.behaviors,'names');
        names = handles.basicParamsStruct.behaviors.names;
        if numel(names)>0
          namestr = String.cellstr2CommaSepList(names);
        else
          namestr = '';
        end
        set(handles.editName,'String',namestr);
      else
        set(handles.editName,'String','');
      end

      fnames = {'scorefilename','moviefilename','trxfilename'};
      boxnames = {'editscorefilename','editmoviefilename','edittrxfilename'};
      for ndx = 1:numel(fnames)
        curf = fnames{ndx};
        curbox = boxnames{ndx};
        if isfield(handles.basicParamsStruct.file,curf);
          val = handles.basicParamsStruct.file.(curf);
          if iscellstr(val)
            val = String.cellstr2CommaSepList(val);
          end
          set(handles.(curbox),'String',val);
        else
          set(handles.(curbox),'String','');
        end
      end

      % Update the select feature dictionary name
      indexOfFeatureLexicon = find(strcmp(handles.basicParamsStruct.featureLexiconName,handles.featureLexiconNameList));
      if isempty(indexOfFeatureLexicon),
        indexOfFeatureLexicon = 1;
      end
      set(handles.featureconfigpopup,'Value',indexOfFeatureLexicon);
      tfST = handles.featureLexiconNameListIsST(indexOfFeatureLexicon);
      if tfST
        visVal = 'on';
      else
        visVal = 'off';
      end
      set(handles.txST,'Visible',visVal);

      % % Update the list of scores-an-inputs
      % fileNameList = {handles.basicParamsStruct.scoreFeatures(:).classifierfile};
      % set(handles.listbox_inputscores,'String',fileNameList);
      % set(handles.listbox_inputscores,'Value',handles.indexOfScoreFeaturesFile);

      % % Disble the Remove button iff the list of scores-as-inputs is empty
      % set(handles.pushbutton_removelist,'enable',offIff(isempty(fileNameList)));

      return
    end

    function handles = updateFigurePosition(app, handles)
      % -------------------------------------------------------------------------

      % Update the figure to match the current mode (which is in the model)
      if strcmp(handles.mode,'basic')
        curPos = get(handles.figureProjectSetup,'Position');
        set(handles.figureProjectSetup,'Position',[curPos(1:2) handles.basicSize]);
        set(handles.togglebutton_advanced,'Value',0);
      else
        curPos = get(handles.figureProjectSetup,'Position');
        set(handles.figureProjectSetup,'Position',[curPos(1:2) handles.advancedSize]);
        set(handles.togglebutton_advanced,'Value',1);
      end
      return
    end

    function updateFileNameEdit(app, handles, editName)
      % -------------------------------------------------------------------------

      % Update the named file name edit to match the model.
      varName=editName(5:end);  % trim 'edit' off of start
      val = handles.basicParamsStruct.file.(varName);
      if iscellstr(val)
        val = String.cellstr2CommaSepList(val);
      end
      set(handles.(editName),'String',val);
      return
    end

    function warnIfInvalid(app, varName, whatHappened)
      % -------------------------------------------------------------------------

      % Throw up a warning dialog if whatHappened equals 'invalidEntry'
      if isequal(whatHappened,'invalidEntry')
        message=...
          sprintf(['The name specified for %s cannot have special characters.'...
          'Please use only alphanumeric characters and underscore.'],varName);
        title='Invalid File Name';
        uiwait(warndlg(message,title,'modal'));
      end
      return
    end

    % Update components that require runtime configuration
    function addRuntimeConfigurations(app)

      % Set component properties that require runtime configuration
      app.config_table.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
      app.config_table.ColumnFormat = {[] []};
    end
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function ProjectSetup_OpeningFcn(app, varargin)
      % -------------------------------------------------------------------------
      % --- Executes just before ProjectSetup is made visible.

      % added by KB 20260714 to fix bug where the advanced config table was
      % showing up (and the whole dialog was squashed) in basic mode.
      % App Designer uifigures default to AutoResizeChildren='on', which
      % rescales every child when the figure is resized.  updateFigurePosition()
      % resizes the figure specifically to clip the advanced controls out of
      % view, so the children must stay put.  The old GUIDE figure had no
      % auto-resize behaviour, which is why this worked before the migration.
      app.figureProjectSetup.AutoResizeChildren = 'off';

      % Add runtime required configuration - Added by Migration Tool
      addRuntimeConfigurations(app);

      % Ensure that the app appears on screen when run
      movegui(app.figureProjectSetup, 'onscreen');

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

      % ProjectSetup(p1,v1,...)
      %
      % ProjectSetup configures a JAABA project. It can optionally accept an
      % existing project configuration (basicParamsStruct). It returns a
      % configured project in the supplied 'handleobj'. A project configuration
      % currently looks like a stripped-down Macguffin and consists of
      %   - Names of behaviors/classifiers
      %   - Name of experimental movie files
      %   - etc.
      %
      % Call ProjectSetup as follows:
      % hobj = HandleObj;
      % uiwait(ProjectSetup('handleobj',hobj,...<other args>...));
      % processResultsOrDoWhatever(hobj.data); % hobj.data is a Macguffin
      %
      % P-V arguments:
      % * basicParamsStruct: Either [], or Macguffin (or Macguffin-like struct).
      % Supplies initial values for project parameters. If basicParamStruct==[],
      % then we are creating a new project.
      % * handleobj: A HandleObj.

      handles.output = hObject;

      [figureJLabel, ...
        basicParamsStruct,...
        defaultmoviefilename,...
        defaultmovieindexfilename,...
        defaulttrxfilename,...
        defaultlbl_file,...
        handles.handleobj] = ...
        myparse(varargin,...
        'figureJLabel',[],...
        'basicParamsStruct',[],...
        'defaultmoviefilename',0,...
        'defaultmovieindexfilename',0,...
        'defaulttrxfilename',0,...
        'defaultlbl_file',0,...
        'handleobj',[]);
      handles.new = isempty(basicParamsStruct);
      handles.figureJLabel = figureJLabel;
      handles.basicParamsStruct = basicParamsStruct;

      adjustColorsIfMac(hObject);

      % Need to derive the basic and advanced sizes (part of the model) from
      % the current figure dimensions
      handles = setBasicAndAdvancedSizesToMatchFigure(app, handles);
      handles.mode = 'basic';
      handles = updateFigurePosition(app, handles);
      centerOnParentFigure(hObject,figureJLabel);
      % ADD THIS FOR DEBUGGING WITH AUTOMATION
      % set(handles.figureProjectSetup,'Position',[413   588   585   347]);

      % Initialize the list of possible feature lexicon names
      [handles.featureLexiconNameList,xmlList] = getFeatureLexiconListsFromXML();
      handles.featureLexiconNameListIsST = cellfun(@xmlParamsIsST,xmlList);
      assert(numel(handles.featureLexiconNameList)==numel(handles.featureLexiconNameListIsST));

      % Populate the featureLexiconName popuplist with options
      set(handles.featureconfigpopup,'String',handles.featureLexiconNameList);

      if isempty(basicParamsStruct)
        % New project
        warnst = warning('off','MATLAB:structOnObject');
        handles.basicParamsStruct = struct(Macguffin('flies')); % the default featureLexiconName
        warning(warnst);
        if ischar(defaultmoviefilename)
          handles.basicParamsStruct.file.moviefilename = defaultmoviefilename;
        end
        if ischar(defaultmovieindexfilename)
          handles.basicParamsStruct.file.movieindexfilename = defaultmovieindexfilename;
        end
        if ischar(defaulttrxfilename)
          handles.basicParamsStruct.file.trxfilename = defaulttrxfilename;
        end
        if ischar(defaultlbl_file),
          handles.basicParamsStruct.lbl_file = defaultlbl_file;
        end
      else
        warnst = warning('off','MATLAB:structOnObject');
        handles.basicParamsStruct = struct(basicParamsStruct);
        warning(warnst);

        % Quirk: basicParamsStruct.behaviors
        % For existing projects basicParamsStruct.behaviors.names includes the
        % no-behavior names (eg 'None', 'No_Chase', etc). In ProjectSetup, the
        % user currently only specifies the positive names.
        % So, within ProjectSetup, we truncate .behaviors.names to just the real
        % names; as a quirk, we leave .behaviors.labelcolors unmodified, so
        % .labelcolors DOES include no-behaviors, as the user may want to change
        % those colors.
        behnames = Labels.verifyBehaviorNames(handles.basicParamsStruct.behaviors.names);
        handles.basicParamsStruct.behaviors.names = behnames;
      end

      if ~isfield(handles.basicParamsStruct,'extra'),
        handles.basicParamsStruct.extra = struct;
      end
      if ~isfield(handles.basicParamsStruct.extra,'usePastOnly'),
        handles.basicParamsStruct.extra.usePastOnly = false;
      end

      % Update the configuration table in the GUI
      guidata(hObject,handles);
      updateConfigTable(app, handles);

      set(hObject,'name',fif(handles.new,'New...','Edit...'));
      onoff = fif(handles.new,'on','off');
      set(handles.featureconfigpopup,'enable',onoff);
      set(handles.pushbutton_copy,'visible',onoff);
      set(handles.editName,'enable',onoff);
      set(handles.edittrxfilename,'enable',onoff);
      set(handles.pushbutton_perframe,'visible',onoff);

      % Set the current score-as-feature file
      fileNameList = {handles.basicParamsStruct.scoreFeatures(:).classifierfile};
      handles.indexOfScoreFeaturesFile = fif(isempty(fileNameList),[],1);

      guidata(hObject, handles);

      % Update all the text fields in the figure
      updateEditsListboxesAndPopupmenus(app, handles);

      % Make the figure visible
      set(hObject,'Visible','on');
      drawnow; pause(3);
      uiwait(hObject);

      return
    end

    % Value changed function: checkbox_st
    function checkbox_st_Callback(app, event)
      % --- Executes on button press in checkbox_st.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to checkbox_st (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hint: get(hObject,'Value') returns toggle state of checkbox_st

      if ~get(hObject,'Value')
        handles.basicParamsStruct.stFeatures = false;
        guidata(hObject,handles);
        return;
      end

      fname = uigetdir('.','Select an example JAABA experiment directory');
      if fname == 0
        mov_file = '';
        trx_file = '';
        set(hObject,'Value',0);
        return
      else
        mov_file = fullfile(fname,handles.basicParamsStruct.file.moviefilename);
        trx_file = fullfile(fname,handles.basicParamsStruct.file.trxfilename);
        if ~exist(mov_file,'file')
          errmsg = sprintf('Movie file %s does not exist',mov_file);
          errordlg(errmsg);
          set(hObject,'Value',0);
          return;
        end
        if ~exist(trx_file,'file'),trx_file=''; end
      end

      set(handles.figureProjectSetup, 'pointer', 'watch')
      [useST,stInfo] = spaceTimeOptions('mov_file',mov_file,...
        'trx_file',trx_file,...
        'prev_st',handles.basicParamsStruct.stInfo);

      set(handles.figureProjectSetup, 'pointer', 'arrow')
      if useST
        handles.basicParamsStruct.stFeatures = true;
        handles.basicParamsStruct.stInfo = stInfo;
      else
        handles.basicParamsStruct.stFeatures = false;
        set(hObject,'Value',0);
      end
      guidata(hObject,handles);
    end

    % Cell edit callback: config_table
    function config_table_CellEditCallback(app, event)
      % -------------------------------------------------------------------------
      % --- Executes when entered data in editable cell(s) in config_table.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to config_table (see GCBO)
      % eventdata  structure with the following fields (see UITABLE)
      %	Indices: row and column indices of the cell(s) edited
      %	PreviousData: previous data for the cell(s) edited
      %	EditData: string(s) entered by the user
      %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
      %	Error: error string when failed to convert EditData to appropriate value for Data
      % handles    structure with handles and user data (see GUIDATA)
      if isempty(eventdata.Indices); return; end
      data = get(handles.config_table,'Data');
      ndx = eventdata.Indices(1);
      if eventdata.Indices(2) == 2
        handles = EditConfigValue(app, handles,data{ndx,1},data{ndx,2});
      else
        handles = EditConfigName(app, handles,eventdata.PreviousData,eventdata.NewData);
      end
      updateEditsListboxesAndPopupmenus(app, handles);
      guidata(hObject,handles);
    end

    % Value changed function: editName
    function editName_Callback(app, event)
      % -------------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to editName (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Hints: get(hObject,'String') returns contents of editName as text
      %        str2double(get(hObject,'String')) returns contents of editName as a double
      name = String.commaSepList2CellStr(get(hObject,'String'));
      if any(cellfun(@(x)isempty(regexp(x,'^[a-zA-Z][\w_,]*$','once','start')),name));
        uiwait(warndlg(['The behavior name cannot have special characters.'...
          'Please use only alphanumeric characters and _']));
        updateEditsListboxesAndPopupmenus(app, handles);
        return
      end

      handles.basicParamsStruct.behaviors.names = name;
      if numel(name)>1
        % multiple classifiers; set labelcolors
        nlabels = 2*numel(name);
        clrs = Labels.cropOrAugmentLabelColors(zeros(1,0),nlabels,'lines');
        handles.basicParamsStruct.behaviors.labelcolors = clrs;
      end
      handles.basicParamsStruct.file.scorefilename = cellfun(@(x)sprintf('scores_%s.mat',x),name,'uni',0);
      guidata(hObject,handles);
      updateEditsListboxesAndPopupmenus(app, handles);
      updateConfigTable(app, handles);
      return
    end

    % Value changed function: editmoviefilename
    function editmoviefilename_Callback(app, event)
      % -------------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      editName=get(hObject,'tag');
      handles = fileNameEditTwiddled(app, handles,editName);
      guidata(hObject,handles);
    end

    % Value changed function: editscorefilename
    function editscorefilename_Callback(app, event)
      % -------------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      editName=get(hObject,'tag');
      handles = fileNameEditTwiddled(app, handles,editName);
      guidata(hObject,handles);
    end

    % Value changed function: edittrxfilename
    function edittrxfilename_Callback(app, event)
      % -------------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      editName=get(hObject,'tag');
      handles = fileNameEditTwiddled(app, handles,editName);
      guidata(hObject,handles);
    end

    % Value changed function: featureconfigpopup
    function featureconfigpopup_Callback(app, event)
      % -------------------------------------------------------------------------
      % --- Executes on selection change in featureconfigpopup.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to featureconfigpopup (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      iFeatureLexicon=get(hObject,'Value');
      featureLexiconNameNew = handles.featureLexiconNameList{iFeatureLexicon};
      % Save the current values of the main four fields
      % (Note that any changes in the advanced part of the window will be lost.)
      behaviorName=handles.basicParamsStruct.behaviors.names;
      movieFileName=handles.basicParamsStruct.file.moviefilename;
      movieIndexFileName=handles.basicParamsStruct.file.movieindexfilename;
      trackFileName=handles.basicParamsStruct.file.trxfilename;
      scoreFileName=handles.basicParamsStruct.file.scorefilename;
      % Replace basicParamsStruct with one appropriate to the new feature lexicon
      % name
      old=warning('query','MATLAB:structOnObject');
      warning('off','MATLAB:structOnObject');  % turn off annoying warning
      handles.basicParamsStruct = struct(Macguffin(featureLexiconNameNew));
      warning(old);  % restore annoying warning
      % Now restore fields from the old basicParamsStruct
      handles.basicParamsStruct.behaviors.names=behaviorName;
      handles.basicParamsStruct.file.moviefilename=movieFileName;
      handles.basicParamsStruct.file.movieindexfilename=movieIndexFileName;
      handles.basicParamsStruct.file.trxfilename=trackFileName;
      handles.basicParamsStruct.file.scorefilename=scoreFileName;
      guidata(hObject,handles);
      updateConfigTable(app, handles);
      updateEditsListboxesAndPopupmenus(app, handles);
      return
    end

    % Close request function: figureProjectSetup
    function figureProjectSetup_CloseRequestFcn(app, event)
      % -------------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % Hint: delete(hObject) closes the figure
      %uiresume(hObject);
      handles.outfile = 0;
      guidata(hObject,handles);
      delete(app);

      %pushbutton_cancel_Callback(hObject, eventdata, handles)
      return
    end

    % Window key press function: figureProjectSetup
    function figureProjectSetup_WindowKeyPressFcn(app, event)
      % -------------------------------------------------------------------------
      % --- Executes on key press with focus on figureProjectSetup and none of its controls.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to figureProjectSetup (see GCBO)
      % eventdata  structure with the following fields (see FIGURE)
      %	Key: name of the key that was pressed, in lower case
      %	Character: character interpretation of the key(s) that was pressed
      %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
      % handles    structure with handles and user data (see GUIDATA)

      if isequal(eventdata.Key,'f') && isControlLike(eventdata.Modifier)
        handles.basicParamsStruct.file.moviefilename = 'movie.ufmf';
        handles.basicParamsStruct.file.trxfilename = 'registered_trx.mat';
        guidata(hObject,handles);  % write the changes to the figure
        updateEditsListboxesAndPopupmenus(app, handles);
      end

      return
    end

    % Button pushed function: pushbutton_addadvanced
    function pushbutton_addadvanced_Callback(app, event)
      % -------------------------------------------------------------------------
      % --- Executes on button press in pushbutton_addadvanced.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      in = inputdlg({'Configuration Parameter Name','Configuration Parameter Value'});
      if isempty(in) || length(in) < 2
        return;
      end

      handles = AddConfig(app, handles,in{1},in{2});
      updateConfigTable(app, handles);
      updateEditsListboxesAndPopupmenus(app, handles);
      guidata(hObject,handles);
    end

    % Button pushed function: pushbutton_cancel
    function pushbutton_cancel_Callback(app, event)
      % -------------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      handles.outfile = 0;
      guidata(hObject,handles);
      delete(app);
      %uiresume(handles.figureProjectSetup);
      return
    end

    % Button pushed function: pushbutton_copy
    function pushbutton_copy_Callback(app, event)
      % -------------------------------------------------------------------------
      % --- Executes on button press in pushbutton_copy.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to pushbutton_copy (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      [fnames,pname] = uigetfile('*.jab','Select a project to copy settings from');
      if fnames == 0; return; end;

      list = {'Target Type','Behavior Name and File Names','Window Features',...
        'List of perframe features','Classifier files used as input',...
        'Classifier Settings',...
        'Advanced Parameters'};

      dstr = {'Select the parameters to copy.','Use Control/Command click to select multiple entries'};
      [sel,ok] = listdlg('PromptString',dstr,'ListSize',[350 120],'Name','Parameters to copy',...
        'ListString',list);
      sellist = list(sel);

      if ok == 0, return; end

      try

        origparams = load(fullfile(pname,fnames),'-mat');

        if ismember('Target Type',sellist)
          handles.basicParamsStruct.featureLexiconName = origparams.x.featureLexiconName;
          handles.basicParamsStruct.featureLexicon = origparams.x.featureLexicon;
          handles.basicParamsStruct.fromAPT = origparams.x.fromAPT;
          handles.basicParamsStruct.aptInfo = origparams.x.aptInfo;
          if origparams.x.fromAPT
            set(handles.radiobutton_apt,'Value',1);
          end
        end

        if ismember('Behavior Name and File Names',sellist)
          behnames = origparams.x.behaviors.names;
          isNone=strcmpi('none',behnames);
          behnames=behnames(~isNone);
          handles.basicParamsStruct.behaviors.names = behnames;
          handles.basicParamsStruct.file = origparams.x.file;
        end


        if ismember('List of perframe features',sellist)
          if ~isequal(handles.basicParamsStruct.featureLexicon,origparams.x.featureLexicon),
            res = questdlg(['Target type (or one of the target settings) are not the same for the current project '...
              'and the original project. Are you sure you want to import the list of perframe features?'],...
              'Import list of perframe features','Yes','No','No');
            if strcmp(res,'Yes')
              handles.basicParamsStruct.sublexiconPFNames= origparams.x.sublexiconPFNames;
            end
          else
            handles.basicParamsStruct.sublexiconPFNames= origparams.x.sublexiconPFNames;

          end
        end

        if ismember('Window Features',sellist),
          if ~isequal(handles.basicParamsStruct.featureLexicon,origparams.x.featureLexicon) || ...
              ~isequal(handles.basicParamsStruct.sublexiconPFNames,origparams.x.sublexiconPFNames)
            res = questdlg(['Target type or list of perframe features (or one of the traget settings) are not the same for the current project '...
              'and the original project. Are you sure you want to import the window features?'],...
              'Import Window features','Yes','No','No');
            if strcmp(res,'Yes')
              handles.basicParamsStruct.windowFeaturesParams = origparams.x.windowFeaturesParams;
            end
          else
            handles.basicParamsStruct.windowFeaturesParams = origparams.x.windowFeaturesParams;
          end
        end


        if ismember('Classifier files used as input', sellist);
          handles.basicParamsStruct.scoreFeatures = origparams.x.scoreFeatures;
        end

        if ismember('Classifier Settings',sellist),
          clf_params_specific = {'classifierStuff.type'
            'classifierStuff.postProcessParams'
            'classifierStuff.trainingParams'
            'classifierStuff.savewindowdata'
            'classifierStuff.selFeatures'
            'classifierStuff.predictOnlyCurrentFly'
            };

          for str = clf_params_specific(:)',
            try %#ok<TRYNC>
              eval(sprintf('handles.basicParamsStruct.%s = origparams.x.%s;',str{1},str{1}));
            end
          end

        end


        if ismember('Advanced Parameters',sellist),
          adv_params_specific = {'behaviors.labelcolors'
            'behaviors.unknowncolor'
            'trxGraphicParams'
            'extra'};

          for str = adv_params_specific(:)',
            try %#ok<TRYNC>
              eval(sprintf('handles.basicParamsStruct.%s = origparams.x.%s;',str{1},str{1}));
            end
          end

        end

        guidata(hObject,handles);
        updateEditsListboxesAndPopupmenus(app, handles);
        updateConfigTable(app, handles);

      catch ME,
        uiwait(warndlg(sprintf('Could not copy settings from previous project:%s',ME.message)));
      end
      return
    end

    % Button pushed function: pushbutton_done
    function pushbutton_done_Callback(app, event)
      % -------------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      behaviorName = handles.basicParamsStruct.behaviors.names;
      if isempty(behaviorName)
        uiwait(errordlg('You must enter a behavior name.', ...
          'No behavior name', ...
          'modal'));
        return
      end

      % Check for an empty track file name
      trackFileName = handles.basicParamsStruct.file.trxfilename;
      if isempty(trackFileName)
        uiwait(errordlg('You must enter a track file name.', ...
          'No track file name', ...
          'modal'));
        return
      end

      % Check for an empty score file name
      scoreFileName = handles.basicParamsStruct.file.scorefilename;
      if isempty(scoreFileName)
        uiwait(errordlg('You must enter a score file name.', ...
          'No score file name', ...
          'modal'));
        return
      end

      nbehavior = numel(handles.basicParamsStruct.behaviors.names);
      assert(iscellstr(handles.basicParamsStruct.file.scorefilename));
      nscorefile = numel(handles.basicParamsStruct.file.scorefilename);
      if nbehavior~=nscorefile
        uiwait(errordlg('The number of score files must match the number of behaviors.', ...
          'Invalid score file names', ...
          'modal'));
        return
      end

      % if movie is an mjpg, ask for an index file
      if ischar(handles.basicParamsStruct.file.moviefilename)
        [~,~,ext] = fileparts(handles.basicParamsStruct.file.moviefilename);
      else
        [~,~,ext] = fileparts(handles.basicParamsStruct.file.moviefilename{1});
      end
      if ismember(lower(ext),{'.mjpg','.mjpeg'}),

        res = questdlg('Is this an indexed MJPG file? If so, you will be prompted to select the corresponding index file location.');
        if strcmpi(res,'Yes'),
          if isfield(handles.basicParamsStruct.file,'movieindexfilename') && ...
              ischar(handles.basicParamsStruct.file.movieindexfilename),
            defaultindexfile = handles.basicParamsStruct.file.movieindexfilename;
          else
            defaultindexfile = 'index.txt';
          end
          res = inputdlg({'MJPG index file name: '},'MJPG index file name',1,{defaultindexfile});
          % cancel
          if isempty(res),
            return;
          end
          handles.basicParamsStruct.file.movieindexfilename = res{1};
        else
          handles.basicParamsStruct.file.movieindexfilename = 0;
        end

      else
        handles.basicParamsStruct.file.movieindexfilename = 0;
      end

      % Finish initialization of basicParamsStruct and return as Macguffin in handleobj
      handles.basicParamsStruct.behaviors = ...
        enforceBehaviorParamConstraints(app, handles.basicParamsStruct.behaviors,true);

      if handles.new
        for i = nbehavior:-1:1
          cs(i,1) = ClassifierStuff();
          wfp{i,1} = handles.basicParamsStruct.windowFeaturesParams{1};
        end
        handles.basicParamsStruct.classifierStuff = cs;
        handles.basicParamsStruct.windowFeaturesParams = wfp;
      end

      basicParamsStruct = handles.basicParamsStruct;
      % AL: Not sure why we return a Macguffin vs just the struct, prob doesn't
      % make a difference
      if basicParamsStruct.fromAPT
        behaviorName=basicParamsStruct.behaviors.names;
        labelColors=basicParamsStruct.behaviors.labelcolors;
        movieFileName=basicParamsStruct.file.moviefilename;
        movieIndexFileName=basicParamsStruct.file.movieindexfilename;
        trackFileName=basicParamsStruct.file.trxfilename;
        scoreFileName=basicParamsStruct.file.scorefilename;
        wfparams=basicParamsStruct.windowFeaturesParams;
        cstuff=basicParamsStruct.classifierStuff;
        sublexcon = basicParamsStruct.sublexiconPFNames;
        everythingParams = Macguffin(basicParamsStruct.featureLexiconName,basicParamsStruct.aptInfo);
        everythingParams.behaviors.names=behaviorName;
        everythingParams.behaviors.labelcolors=labelColors;
        everythingParams.file.moviefilename=movieFileName;
        everythingParams.file.movieindexfilename=movieIndexFileName;
        everythingParams.file.trxfilename=trackFileName;
        everythingParams.file.scorefilename=scoreFileName;
        everythingParams.windowFeaturesParams=wfparams;
        everythingParams.classifierStuff=cstuff;
        everythingParams.sublexiconPFNames = sublexcon;
      else
        everythingParams = Macguffin(basicParamsStruct);
      end
      assert(~isempty(handles.handleobj));
      handles.handleobj.data = everythingParams;

      % Delete the ProjectSetup window
      delete(get(hObject,'parent'));
    end

    % Button pushed function: pushbutton_edit_APT
    function pushbutton_edit_APT_Callback(app, event)
      % --- Executes on button press in pushbutton_edit_APT.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to pushbutton_edit_APT (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      % Do stuff if init from APT project is selected
      [fname,fpath] = uigetfile('*.lbl','Select APT project File..',handles.basicParamsStruct.aptInfo.lbl_file);
      if fname == 0,
        return;
      end
      % Get the detail of APT
      lbl_file = fullfile(fpath,fname);
      % aptStruct = APTProject('lbl_file',lbl_file,'aptStruct',handles.basicParamsStruct.aptInfo);
      temp_data = appData;
      APTProject_App(temp_data,'lbl_file',lbl_file,'aptStruct',handles.basicParamsStruct.aptInfo);
      aptStruct = temp_data.aptStruct;

      if isempty(aptStruct.featureLexicon)
        set(handles.radiobutton_apt,'Value',0);
        set(handles.radiobutton_list,'Value',1);
        return;
      end
    end

    % Button pushed function: pushbutton_perframe
    function pushbutton_perframe_Callback(app, event)
      % -------------------------------------------------------------------------
      % --- Executes on button press in pushbutton_perframe.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      featureLexiconName=handles.basicParamsStruct.featureLexiconName;
      sublexiconPFNames = handles.basicParamsStruct.sublexiconPFNames;

      if ~get(handles.radiobutton_apt,'Value')
        featureLexicon=featureLexiconFromFeatureLexiconName(featureLexiconName);
        [featureLexiconPFNames,isSelected,missingPFNames] = ...
          GetAllPerframeList(app, featureLexicon,sublexiconPFNames);

        % If some to-be-calculated PFs are not in the lexicon, warn the user.
        if ~isempty(missingPFNames),
          list = naturalLanguageListFromStringList(missingPFNames);
          if length(missingPFNames)==1
            wstr = ...
              sprintf(['Perframe feature %s is defined in the project file ' ...
              'but is not defined for the current target type.\n' ...
              'Ignoring it.'],...
              list);
          else
            wstr = ...
              sprintf(['Perframe features %s are defined in the project file ' ...
              'but are not defined for the current target type.\n' ...
              'Ignoring them.'],...
              list);
          end
          uiwait(warndlg(wstr));
        end
      else
        featureLexicon = handles.basicParamsStruct.featureLexicon;
        [featureLexiconPFNames,isSelected,missingPFNames] = ...
          GetAllPerframeList(app, featureLexicon,sublexiconPFNames);
        assert(isempty(missingPFNames),'No PF feature should be missing');
      end
      % Put up the dialog that allows users to pick which PFs will be computed

      [isSelected,ok] = listdlg('ListString',featureLexiconPFNames, ...
        'InitialValue',find(isSelected), ...
        'Name','Selecte perframe features',...
        'PromptString',{sprintf('%s-click to',fif(ismac(),'Command','Control')), ...
        'select/deselect perframe features'}, ...
        'ListSize',[250,700]);

      % If the user clicked OK, update the list of to-be-calculated PFs
      if ok,
        sublexiconPFNames=featureLexiconPFNames(isSelected);
        handles.basicParamsStruct.sublexiconPFNames = sublexiconPFNames;
        guidata(hObject,handles);
      end

      return
    end

    % Button pushed function: pushbutton_removeadvanced
    function pushbutton_removeadvanced_Callback(app, event)
      % -------------------------------------------------------------------------
      % --- Executes on button press in pushbutton_removeadvanced.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      data = get(handles.config_table,'Data');

      jUIScrollPane = findjobj(handles.config_table);
      jUITable = jUIScrollPane.getViewport.getView;
      allndx = jUITable.getSelectedRows + 1;
      if numel(allndx)==1 && allndx <1, return, end

      for ndx = allndx(:)'
        handles = RemoveConfig(app, handles,data{ndx,1});
      end
      updateConfigTable(app, handles);
      updateEditsListboxesAndPopupmenus(app, handles);
      guidata(hObject,handles);
      return
    end

    % Value changed function: togglebutton_advanced
    function togglebutton_advanced_Callback(app, event)
      % -------------------------------------------------------------------------
      % --- Executes on button press in togglebutton_advanced.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      handles.mode=fif(get(hObject,'Value'),'advanced','basic');
      handles = updateFigurePosition(app, handles);
      guidata(hObject,handles);
      return
    end

    function configpopup(app,eventdata)
      featureconfigpopup_Callback(app, eventdata)
    end

    % Selection changed function: uibuttongroup1
    function uibuttongroup1_SelectionChangedFcn(app, event)
      % --- Executes when selected object is changed in uibuttongroup1.

      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      % hObject    handle to the selected object in uibuttongroup1
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)

      if ~get(handles.radiobutton_apt,'Value')
        set(handles.featureconfigpopup,'Enable','on');
        set(handles.text_apt,'Visible','Off');
        set(handles.pushbutton_edit_APT,'Visible','off');
        configpopup(app,eventdata);
        return;
      end
      % Do stuff if init from APT project is selected
      if isfield(handles.basicParamsStruct,'lbl_file') && ...
          ischar(handles.basicParamsStruct.lbl_file),
        defaultlbl_file = handles.basicParamsStruct.lbl_file;
      else
        defaultlbl_file = '';
      end
      debug = false;
      if debug
        Q = load('~/temp/aptprojdata');
        aptStruct = Q.qq;
      else
        [fname,fpath] = uigetfile('*.lbl','Select APT project File..',defaultlbl_file);
        if fname == 0
          set(handles.radiobutton_apt,'Value',0);
          set(handles.radiobutton_list,'Value',1);
          return;
        end
        % Get the detail of APT
        lbl_file = fullfile(fpath,fname);
        % aptStruct = APTProject('lbl_file',lbl_file);
        temp_data = appData;
        app_apt = APTProject_App(temp_data,'lbl_file',lbl_file);
        waitfor(app_apt);
        aptStruct = temp_data.aptStruct;
      end

      if isempty(aptStruct.featureLexicon)
        set(handles.radiobutton_apt,'Value',0);
        set(handles.radiobutton_list,'Value',1);
        return;
      end
      % Update the GUI.
      set(handles.featureconfigpopup,'Enable','off');
      set(handles.text_apt,'Visible','On');
      set(handles.text_apt,'String',aptStruct.featureLexiconName);
      set(handles.pushbutton_edit_APT,'Visible','on');

      % Save the current values of the main four fields
      % (Note that any changes in the advanced part of the window will be lost.)
      behaviorName=handles.basicParamsStruct.behaviors.names;
      movieFileName=handles.basicParamsStruct.file.moviefilename;
      movieIndexFileName=handles.basicParamsStruct.file.movieindexfilename;
      trackFileName=handles.basicParamsStruct.file.trxfilename;
      scoreFileName=handles.basicParamsStruct.file.scorefilename;

      handles.basicParamsStruct.featureLexiconName = aptStruct.origFeatureLexiconName;
      % Replace basicParamsStruct with one appropriate to the new feature lexicon
      % name
      old=warning('query','MATLAB:structOnObject');
      warning('off','MATLAB:structOnObject');  % turn off annoying warning
      handles.basicParamsStruct = struct(Macguffin(handles.basicParamsStruct,aptStruct,false));
      warning(old);  % restore annoying warning
      % Now restore fields from the old basicParamsStruct
      handles.basicParamsStruct.behaviors.names=behaviorName;
      handles.basicParamsStruct.file.moviefilename=movieFileName;
      handles.basicParamsStruct.file.movieindexfilename=movieIndexFileName;
      handles.basicParamsStruct.file.trxfilename=trackFileName;
      handles.basicParamsStruct.file.scorefilename=scoreFileName;
      guidata(hObject,handles);
      updateConfigTable(app,handles);
      updateEditsListboxesAndPopupmenus(app, handles);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figureProjectSetup = uifigure('Visible', 'off');
      colormap(app.figureProjectSetup, 'parula');
      app.figureProjectSetup.Name = 'Project Setup';
      app.figureProjectSetup.CloseRequestFcn = createCallbackFcn(app, @figureProjectSetup_CloseRequestFcn, true);
      app.figureProjectSetup.WindowKeyPressFcn = createCallbackFcn(app, @figureProjectSetup_WindowKeyPressFcn, true);
      app.figureProjectSetup.Tag = 'figureProjectSetup';
      app.uibuttongroup1 = uibuttongroup(app.figureProjectSetup);
      app.uibuttongroup1.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup1_SelectionChangedFcn, true);
      app.uibuttongroup1.Title = 'Target Type';
      app.uibuttongroup1.Tag = 'uibuttongroup1';
      app.radiobutton_list = uiradiobutton(app.uibuttongroup1);
      app.radiobutton_list.Tag = 'radiobutton_list';
      app.radiobutton_list.Text = 'Select from list';
      app.radiobutton_list.Value = true;
      app.radiobutton_apt = uiradiobutton(app.uibuttongroup1);
      app.radiobutton_apt.Tag = 'radiobutton_apt';
      app.radiobutton_apt.Text = 'Import from APT project';
      app.text_apt = uilabel(app.uibuttongroup1);
      app.text_apt.Tag = 'text_apt';
      app.text_apt.Visible = 'off';
      app.text_apt.Text = 'APT project';
      app.checkbox_st = uicheckbox(app.uibuttongroup1);
      app.checkbox_st.ValueChangedFcn = createCallbackFcn(app, @checkbox_st_Callback, true);
      app.checkbox_st.Tag = 'checkbox_st';
      app.checkbox_st.Text = 'Add video based (hog/hof) features';
      app.text_st = uilabel(app.uibuttongroup1);
      app.text_st.Tag = 'text_st';
      app.text_st.Visible = 'off';
      app.text_st.Text = 'Video Features';
      app.pushbutton_edit_APT = uibutton(app.uibuttongroup1, 'push');
      app.pushbutton_edit_APT.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_edit_APT_Callback, true);
      app.pushbutton_edit_APT.Tag = 'pushbutton_edit_APT';
      app.pushbutton_edit_APT.Visible = 'off';
      app.pushbutton_edit_APT.Tooltip = 'Done creating the new project';
      app.pushbutton_edit_APT.Text = 'Edit...';
      app.editName = uieditfield(app.figureProjectSetup, 'text');
      app.editName.ValueChangedFcn = createCallbackFcn(app, @editName_Callback, true);
      app.editName.Tag = 'editName';
      app.editName.Tooltip = 'Behavior Name';
      app.editName.Value = 'Edit Text';
      app.featureconfigpopup = uidropdown(app.figureProjectSetup);
      app.featureconfigpopup.Items = {'Select', 'bb'};
      app.featureconfigpopup.ValueChangedFcn = createCallbackFcn(app, @featureconfigpopup_Callback, true);
      app.featureconfigpopup.Tag = 'featureconfigpopup';
      app.featureconfigpopup.Tooltip = 'Select the type of the target.';
      app.featureconfigpopup.Value = 'Select';
      app.text3 = uilabel(app.figureProjectSetup);
      app.text3.Tag = 'text3';
      app.text3.Tooltip = 'Behavior Name';
      app.text3.Text = 'Behavior Name';
      app.editscorefilename = uieditfield(app.figureProjectSetup, 'text');
      app.editscorefilename.ValueChangedFcn = createCallbackFcn(app, @editscorefilename_Callback, true);
      app.editscorefilename.Tag = 'editscorefilename';
      app.editscorefilename.Tooltip = 'File where output of JAABA (scores) will be stored in the experiment directory';
      app.editscorefilename.Value = 'Edit Text';
      app.text6 = uilabel(app.figureProjectSetup);
      app.text6.Tag = 'text6';
      app.text6.Tooltip = 'File where output of JAABA (scores) will be stored in the experiment directory';
      app.text6.Text = {'Output/Score'; 'File Name'};
      app.editmoviefilename = uieditfield(app.figureProjectSetup, 'text');
      app.editmoviefilename.ValueChangedFcn = createCallbackFcn(app, @editmoviefilename_Callback, true);
      app.editmoviefilename.Tag = 'editmoviefilename';
      app.editmoviefilename.Tooltip = 'The movie filename should be the same in the experiment directories';
      app.editmoviefilename.Value = 'Edit Text';
      app.edittrxfilename = uieditfield(app.figureProjectSetup, 'text');
      app.edittrxfilename.ValueChangedFcn = createCallbackFcn(app, @edittrxfilename_Callback, true);
      app.edittrxfilename.Tag = 'edittrxfilename';
      app.edittrxfilename.Tooltip = 'The tracking data filename should be the same in the experiment directories';
      app.edittrxfilename.Value = 'Edit Text';
      app.text9 = uilabel(app.figureProjectSetup);
      app.text9.Tag = 'text9';
      app.text9.Tooltip = 'The movie filename should be the same in the experiment directories';
      app.text9.Text = {'Movie'; 'File Name'};
      app.text10 = uilabel(app.figureProjectSetup);
      app.text10.Tag = 'text10';
      app.text10.Tooltip = 'The tracking data filename should be the same in the experiment directories';
      app.text10.Text = {'Tracking Data'; 'File Name'};
      app.pushbutton_cancel = uibutton(app.figureProjectSetup, 'push');
      app.pushbutton_cancel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancel_Callback, true);
      app.pushbutton_cancel.Tag = 'pushbutton_cancel';
      app.pushbutton_cancel.Tooltip = 'Cancel creating the new project';
      app.pushbutton_cancel.Text = 'Cancel';
      app.pushbutton_done = uibutton(app.figureProjectSetup, 'push');
      app.pushbutton_done.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_done_Callback, true);
      app.pushbutton_done.Tag = 'pushbutton_done';
      app.pushbutton_done.Tooltip = 'Done creating the new project';
      app.pushbutton_done.Text = 'Done';
      app.pushbutton_copy = uibutton(app.figureProjectSetup, 'push');
      app.pushbutton_copy.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_copy_Callback, true);
      app.pushbutton_copy.Tag = 'pushbutton_copy';
      app.pushbutton_copy.Tooltip = 'Copy Settings from an existing project';
      app.pushbutton_copy.Text = 'Copy Settings From ...';
      app.togglebutton_advanced = uibutton(app.figureProjectSetup, 'state');
      app.togglebutton_advanced.ValueChangedFcn = createCallbackFcn(app, @togglebutton_advanced_Callback, true);
      app.togglebutton_advanced.Tag = 'togglebutton_advanced';
      app.togglebutton_advanced.Tooltip = 'Show/Hide advanced options';
      app.togglebutton_advanced.Text = 'Advanced';
      app.config_table = uitable(app.figureProjectSetup);
      app.config_table.ColumnName = {'Parameter'; 'Value'};
      app.config_table.RowName = '';
      app.config_table.ColumnEditable = [true true];
      app.config_table.CellEditCallback = createCallbackFcn(app, @config_table_CellEditCallback, true);
      app.config_table.Tag = 'config_table';
      app.pushbutton_perframe = uibutton(app.figureProjectSetup, 'push');
      app.pushbutton_perframe.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_perframe_Callback, true);
      app.pushbutton_perframe.Tag = 'pushbutton_perframe';
      app.pushbutton_perframe.Tooltip = 'Select the perframe features to be calculated';
      app.pushbutton_perframe.Text = 'Choose perframe features';
      app.pushbutton_addadvanced = uibutton(app.figureProjectSetup, 'push');
      app.pushbutton_addadvanced.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_addadvanced_Callback, true);
      app.pushbutton_addadvanced.Tag = 'pushbutton_addadvanced';
      app.pushbutton_addadvanced.Tooltip = 'Browse to pick the classifier file';
      app.pushbutton_addadvanced.Text = 'Add';
      app.pushbutton_removeadvanced = uibutton(app.figureProjectSetup, 'push');
      app.pushbutton_removeadvanced.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_removeadvanced_Callback, true);
      app.pushbutton_removeadvanced.Tag = 'pushbutton_removeadvanced';
      app.pushbutton_removeadvanced.Tooltip = 'Remove a classifier from the list';
      app.pushbutton_removeadvanced.Text = 'Remove';
      app.txST = uilabel(app.figureProjectSetup);
      app.txST.Tag = 'txST';
      app.txST.Visible = 'off';
      app.txST.Text = 'ST';
      % Show the figure after all components are created
      app.figureProjectSetup.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ProjectSetup_App(varargin)

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.figureProjectSetup)

        % Execute the startup function
        runStartupFcn(app, @(app)ProjectSetup_OpeningFcn(app, varargin{:}))
      else

        % Focus the running singleton app
        figure(runningApp.figureProjectSetup)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figureProjectSetup)
    end
  end
end